classdef MuMag2022_Toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MuMagToolboxNanoMagnetismGroupUniversityofLuxembourgUIFigure  matlab.ui.Figure
        MuMag2022ToolboxNanoMagnetismGroupUniversityofLuxembourgPanel  matlab.ui.container.Panel
        DemagFitToolPanel               matlab.ui.container.Panel
        SaveResultsButton_2             matlab.ui.control.Button
        DemagFitButton                  matlab.ui.control.Button
        SamplesSpinner_5                matlab.ui.control.Spinner
        SamplesSpinner_5Label           matlab.ui.control.Label
        mu_0H_mathrmdmTmaxEditField     matlab.ui.control.NumericEditField
        mu_0H_mathrmdmTmaxEditFieldLabel  matlab.ui.control.Label
        mu_0H_mathrmdmTminEditField     matlab.ui.control.NumericEditField
        mu_0H_mathrmdmTminEditFieldLabel  matlab.ui.control.Label
        SamplesSpinner_4                matlab.ui.control.Spinner
        SamplesSpinner_4Label           matlab.ui.control.Label
        mu_0H_0mathrmminmTEditField_2   matlab.ui.control.NumericEditField
        mu_0H_0mathrmminmTLabel         matlab.ui.control.Label
        q_mathrmmaxnm1EditField_3       matlab.ui.control.NumericEditField
        q_mathrmmaxnm1EditField_3Label  matlab.ui.control.Label
        SANSGeometryDropDown_4          matlab.ui.control.DropDown
        SANSGeometryDropDown_4Label     matlab.ui.control.Label
        A_mathrmmaxpJmEditField_2       matlab.ui.control.NumericEditField
        A_mathrmmaxpJmEditField_2Label  matlab.ui.control.Label
        A_mathrmminpJmEditField_2       matlab.ui.control.NumericEditField
        A_mathrmminpJmEditField_2Label  matlab.ui.control.Label
        GenerateSyntheticMSANSDataPanel  matlab.ui.container.Panel
        b_mathrmHA1m1EditField_2        matlab.ui.control.NumericEditField
        b_mathrmHA1m1EditField_2Label   matlab.ui.control.Label
        xi_mathrmMnmEditField           matlab.ui.control.NumericEditField
        xi_mathrmMnmEditFieldLabel      matlab.ui.control.Label
        SamplesSpinner_2                matlab.ui.control.Spinner
        SamplesSpinner_2Label           matlab.ui.control.Label
        q_mathrmmaxnm1EditField_2       matlab.ui.control.NumericEditField
        q_mathrmmaxnm1EditField_2Label  matlab.ui.control.Label
        q_mathrmminnm1EditField         matlab.ui.control.NumericEditField
        q_mathrmminnm1EditFieldLabel    matlab.ui.control.Label
        SamplesSpinner                  matlab.ui.control.Spinner
        SamplesSpinnerLabel             matlab.ui.control.Label
        mu_0H_0mTmaxEditField           matlab.ui.control.NumericEditField
        mu_0H_0mTmaxLabel               matlab.ui.control.Label
        mu_0H_0mTminEditField           matlab.ui.control.NumericEditField
        mu_0H_0mTminLabel               matlab.ui.control.Label
        xi_mathrmHnmEditField           matlab.ui.control.NumericEditField
        xi_mathrmHnmEditFieldLabel      matlab.ui.control.Label
        mu_0sqrtlangleM_mathrmz2ranglemTEditField  matlab.ui.control.NumericEditField
        a_mathrmMLabel                  matlab.ui.control.Label
        mu_0sqrtlangleH_mathrmp2ranglemTEditField  matlab.ui.control.NumericEditField
        a_mathrmHLabel                  matlab.ui.control.Label
        sigmacm1EditField               matlab.ui.control.NumericEditField
        sigmastdLabel                   matlab.ui.control.Label
        mu_0M_0mTEditField              matlab.ui.control.NumericEditField
        mu_0M_0mTEditFieldLabel         matlab.ui.control.Label
        mu_0H_mathrmdmTEditField        matlab.ui.control.NumericEditField
        mu_0H_mathrmdmTEditFieldLabel   matlab.ui.control.Label
        SANSGeometryDropDown_2          matlab.ui.control.DropDown
        SANSGeometryDropDown_2Label     matlab.ui.control.Label
        ApJmEditField                   matlab.ui.control.NumericEditField
        ApJmLabel                       matlab.ui.control.Label
        GenSynDataButton                matlab.ui.control.Button
        SweepFitToolPanel               matlab.ui.container.Panel
        SANSGeometryDropDown_3          matlab.ui.control.DropDown
        SANSGeometryDropDown_3Label     matlab.ui.control.Label
        mu_0H_0mathrmminmTEditField_3   matlab.ui.control.NumericEditField
        mu_0H_0mathrmminmTEditField_3Label  matlab.ui.control.Label
        SaveResultsButton               matlab.ui.control.Button
        SweepFitButton                  matlab.ui.control.Button
        SimpleFitToolPanel              matlab.ui.container.Panel
        b_mathrmHA1m1EditField          matlab.ui.control.NumericEditField
        b_H1AmLabel                     matlab.ui.control.Label
        SamplesSpinner_3                matlab.ui.control.Spinner
        SamplesSpinner_3Label           matlab.ui.control.Label
        SANSGeometryDropDown            matlab.ui.control.DropDown
        SANSGeometryDropDownLabel       matlab.ui.control.Label
        mu_0H_0mathrmminmTEditField     matlab.ui.control.NumericEditField
        mu_0H_minmTLabel                matlab.ui.control.Label
        A_mathrmmaxpJmEditField         matlab.ui.control.NumericEditField
        A_mathrmmaxpJmEditFieldLabel    matlab.ui.control.Label
        A_mathrmminpJmEditField         matlab.ui.control.NumericEditField
        A_mathrmminpJmEditFieldLabel    matlab.ui.control.Label
        q_mathrmmaxnm1EditField         matlab.ui.control.NumericEditField
        q_mathrmmax1nmLabel             matlab.ui.control.Label
        SaveResultButton                matlab.ui.control.Button
        CompareResultButton             matlab.ui.control.Button
        SimpleFitButton                 matlab.ui.control.Button
        ImportExperimentalDataPanel     matlab.ui.container.Panel
        InverseColorMapCheckBox         matlab.ui.control.CheckBox
        ColorMapDropDown                matlab.ui.control.DropDown
        ColorMapDropDownLabel           matlab.ui.control.Label
        ShowErrorbarCheckBox            matlab.ui.control.CheckBox
        PlotSANSDatalinButton           matlab.ui.control.Button
        PlotSANSDatalogButton           matlab.ui.control.Button
        ImportSANSDataButton            matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        % Perpendicular Geometry
        function [I_sim, I_res, S_H, S_M] = LorentzianModelPERP(app, q, A, M_s, H_0, H_dem, a_H, a_M, xi_H, xi_M)
            
            % Micromagnetic Model
            mu_0 = 4 * pi * 1e-7;
            H_i = H_0 - H_dem;
            l_H = sqrt((2*A)./(mu_0 .* M_s .* H_i));
            H_eff = H_i .* (1 + l_H.^2 .* q.^2);
            p = M_s./H_eff;
            R_H = p.^2 / 4 .* (2 + 1./sqrt(1 + p));
            R_M = (sqrt(1 + p) - 1)/2;
        
            % Lorentzian functions for S_H, S_M
            S_H = a_H./(1 + q.^2 .* xi_H.^2).^2;
            S_M = a_M./(1 + q.^2 .* xi_M.^2).^2;
        
            % Magnetic SANS cross sections
            I_M = R_H .* S_H + R_M .* S_M;
        
            % Model of I_res
            [~, idx] = max(H_0(:, 1));
      
            I_res = 0.9 * I_M(idx, :);
        
            % Total SANS cross section
            I_sim = I_res + I_M;
            I_sim = I_sim * 1e-2; % in cm^(-1)

        end
        
        function [chi_q, I_res, S_H, S_M, sigma_I_res, sigma_S_H, sigma_S_M] = ...
                LSQ_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A)
            
            % Micromagnetic Model
            mu_0 = 4 * pi * 1e-7;
            H_i = H_0 - H_dem;
            l_H = sqrt((2 * A) ./ (mu_0 .* M_s .* H_i));
            H_eff = H_i .* (1 + l_H.^2 .* q.^2);
            p = M_s ./ H_eff;
            R_H = p.^2 / 4 .* (2 + 1 ./ sqrt(1 + p));
            R_M = (sqrt(1 + p) - 1) / 2;
        
            % Non-negative least squares loop
            L_nu = length(q(1, :));
            S_H = zeros(1, L_nu);
            S_M = zeros(1, L_nu);
            I_res = zeros(1, L_nu);
            sigma_S_H = zeros(1, L_nu);
            sigma_S_M = zeros(1, L_nu);
            sigma_I_res = zeros(1, L_nu);
            for nu = 1:L_nu 
                A = [1./sigma(:, nu), R_H(:, nu)./sigma(:, nu), R_M(:, nu)./sigma(:, nu)];
                y = I_exp(:, nu)./sigma(:, nu);
                c = A.'*y;
                H = A.'*A;
                
                % non-negative linear least squares
                x = lsqnonneg(H, c);
                I_res(1, nu) = x(1);
                S_H(1, nu) = x(2);
                S_M(1, nu) = x(3);
        
                Gamma = inv(H.'*H);
                sigma_I_res(1, nu) = Gamma(1, 1);
                sigma_S_H(1, nu) = Gamma(2, 2);
                sigma_S_M(1, nu) = Gamma(3, 3);
            end

            I_sim = I_res + R_H .* S_H + R_M .* S_M;
            s_q = mean(((I_exp - I_sim)./sigma).^2);
        
            sigma_S_H = sqrt(abs(sigma_S_H .* s_q));
            sigma_S_M = sqrt(abs(sigma_S_M .* s_q));
            sigma_I_res = sqrt(abs(sigma_I_res .* s_q));
        
            chi_q = mean(s_q);

        end

        function chi_q = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A)

            % Micromagnetic Model
            A = abs(A);
            mu_0 = 4 * pi * 1e-7;
            H_i = H_0 - H_dem;
            l_H = sqrt((2 * A) ./ (mu_0 .* M_s .* H_i));
            H_eff = H_i .* (1 + l_H.^2 .* q.^2);
            p = M_s ./ H_eff;


            R_H = p.^2 / 4 .* (2 + 1 ./ sqrt(1 + p));           
            R_M = (sqrt(1 + p) - 1) / 2;
        
            %Non-negative least squares loop
            L_nu = length(q(1, :));
            S_H = zeros(1, L_nu);
            S_M = zeros(1, L_nu);
            I_res = zeros(1, L_nu);
            for nu = 1:L_nu 
                A = [1./sigma(:, nu), R_H(:, nu)./sigma(:, nu), R_M(:, nu)./sigma(:, nu)];
                y = I_exp(:, nu)./sigma(:, nu);
 
                %non-negative linear least squares
                x = lsqnonneg(A.'*A, A.'*y);
                I_res(1, nu) = x(1);
                S_H(1, nu) = x(2);
                S_M(1, nu) = x(3);
        
            end

            I_sim = I_res + R_H .* S_H + R_M .* S_M;
            chi_q = mean(mean(((I_exp - I_sim)./sigma).^2));

        end

        function [A, chi_q, A_opt, chi_q_opt, I_res_opt, S_H_opt, S_M_opt, sigma_I_res, sigma_S_H, sigma_S_M] ...
                = SweepA_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A_1, A_2, A_N)

            A = linspace(A_1, A_2, A_N);
            chi_q = zeros(1, length(A));
            wbar = waitbar(0, 'SimpleFit - Progress');
            for k = 1:A_N  
                [chi_q(k), I_res, S_H, S_M, sigma_I_res, sigma_S_H, sigma_S_M] ...
                    = LSQ_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A(k));
                waitbar(k/A_N, wbar, 'SimpleFit - Progress');
            end
            [~, min_idx] = min(chi_q);
            A_opt = A(min_idx);
            close(wbar)
        
            [chi_q_opt, I_res_opt, S_H_opt, S_M_opt, sigma_I_res, sigma_S_H, sigma_S_M] ...
                = LSQ_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A_opt);
            
        end
 
        function x_4 = OptimA_SPI_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A_1, eps)

                delta = A_1 * 0.1;

                x_1 = A_1 - delta;
                x_2 = A_1;
                x_3 = A_1 + delta;
            
                y_1 = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, x_1);
                y_2 = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, x_2);
                y_3 = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, x_3);
                k = 1;
                x_4 = x_3 + 0.5 * ((x_2 - x_3)^2 * (y_3 - y_1) + (x_1 - x_3)^2 * (y_2 - y_3))/((x_2 - x_3) * (y_3 - y_1) + (x_1 - x_3) * (y_2 - y_3));
                while abs(2 * (x_4 - x_3)/(x_4 + x_3)) > eps && k < 1000
            
                    x_1 = x_2;
                    x_2 = x_3;
                    x_3 = x_4;
            
                    y_1 = y_2;
                    y_2 = y_3;
                    y_3 = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, x_3);
            
                    x_4 = x_3 + 0.5 * ((x_2 - x_3)^2 * (y_3 - y_1) + (x_1 - x_3)^2 * (y_2 - y_3)) / ((x_2 - x_3) * (y_3 - y_1) + (x_1 - x_3) * (y_2 - y_3));

                    k = k + 1;
                end

        end

        function [I, R_H, R_M] = SANS_Model_PERP(app, q, S_H, S_M, I_res, M_s, H_0, H_dem, A)

            % Micromagnetic Model
            mu_0 = 4 * pi * 1e-7;
            H_i = H_0 - H_dem;
            l_H = sqrt((2 .* A)./(mu_0 .* M_s .* H_i));
            H_eff = H_i .* (1 + (l_H.^2) .* (q.^2));
            p = M_s ./ H_eff;
            R_H = (p.^2) ./ 4 .* (2 + 1 ./ sqrt(1 + p));
            R_M = (sqrt(1 + p) - 1) / 2;
            I = I_res + R_H .* S_H + R_M .* S_M;

        end

        function d2_chi_dA2 = FDM2Ord_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A_opt)

            p = 0.001;
            dA = A_opt * p;
            A1 = A_opt - 2*dA;
            A2 = A_opt - 1*dA;
            A3 = A_opt;
            A4 = A_opt + 1*dA;
            A5 = A_opt + 2*dA;
        
            chi1 = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A1);
            chi2 = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A2);
            chi3 = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A3);
            chi4 = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A4);
            chi5 = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A5);
        
            d2_chi_dA2 = (-chi1 + 16 * chi2 - 30 * chi3 + 16 * chi4 - chi5)/(12 * dA^2);

        end


        % Parallel Geometry
        function [I_sim, I_res, S_H] = LorentzianModelPAR(app, q, A, M_s, H_0, H_dem, a_H, xi_H)
            
            % Micromagnetic Model
            mu_0 = 4 * pi * 1e-7;
            H_i = H_0 - H_dem;
            l_H = sqrt((2*A)./(mu_0 .* M_s .* H_i));
            H_eff = H_i .* (1 + l_H.^2 .* q.^2);
            p = M_s./H_eff;
            R_H = p.^2 / 2;
                     
            % Lorentzian functions for S_H
            S_H = a_H^2./(1 + q.^2 .* xi_H.^2).^2;
                     
            % Magnetic SANS cross sections
            I_M = R_H .* S_H;
        
            % Model of I_res
            [~, idx] = max(H_0(:,1));
            I_res = 0.9 * I_M(idx, :);
        
            % Total SANS cross section
            I_sim = I_res + I_M;
            I_sim = I_sim * 1e-2; % in 1/cm

        end

        function [chi_q, I_res, S_H,  sigma_I_res, sigma_S_H] = ...
                LSQ_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A)
            
            % Micromagnetic Model
            mu_0 = 4 * pi * 1e-7;
            H_i = H_0 - H_dem;
            l_H = sqrt((2 * A) ./ (mu_0 .* M_s .* H_i));
            H_eff = H_i .* (1 + l_H.^2 .* q.^2);
            p = M_s ./ H_eff;
            R_H = p.^2 / 2;
                     
            % Non-negative least squares loop
            L_nu = length(q(1, :));
            S_H = zeros(1, L_nu);
            I_res = zeros(1, L_nu);
            sigma_S_H = zeros(1, L_nu);             
            sigma_I_res = zeros(1, L_nu);
            for nu = 1:L_nu 
                A = [1./sigma(:, nu), R_H(:, nu)./sigma(:, nu)];
                y = I_exp(:, nu)./sigma(:, nu);
                c = A.'*y;
                H = A.'*A;
                
                % non-negative linear least squares
                x = lsqnonneg(H, c);
                I_res(1, nu) = x(1);
                S_H(1, nu) = x(2);
                
        
                Gamma = inv(H.'*H);
                sigma_I_res(1, nu) = Gamma(1, 1);
                sigma_S_H(1, nu) = Gamma(2, 2);
                 
            end

            I_sim = I_res + R_H .* S_H;
            s_q = mean(((I_exp - I_sim)./sigma).^2);     
            sigma_S_H = sqrt(abs(sigma_S_H .* s_q));            
            sigma_I_res = sqrt(abs(sigma_I_res .* s_q));        
            chi_q = mean(s_q);

        end

        function chi_q = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A)

            % Micromagnetic Model
            A = abs(A);
            mu_0 = 4 * pi * 1e-7;
            H_i = H_0 - H_dem;
            l_H = sqrt((2 * A) ./ (mu_0 .* M_s .* H_i));
            H_eff = H_i .* (1 + l_H.^2 .* q.^2);
            p = M_s ./ H_eff;
            R_H = p.^2 / 2;
                    
            % Non-negative least squares loop
            L_nu = length(q(1, :));
            S_H = zeros(1, L_nu);           
            I_res = zeros(1, L_nu);
            for nu = 1:L_nu 
                A = [1./sigma(:, nu), R_H(:, nu)./sigma(:, nu)];
                y = I_exp(:, nu)./sigma(:, nu);
                c = A.'*y;
                H = A.'*A;
                
                % non-negative linear least squares
                x = lsqnonneg(H, c);
                I_res(1, nu) = x(1);
                S_H(1, nu) = x(2);
        
    
            end

            I_sim = I_res + R_H .* S_H;
            chi_q = mean(mean(((I_exp - I_sim)./sigma).^2));

        end

        function [A, chi_q, A_opt, chi_q_opt, I_res_opt, S_H_opt, sigma_I_res, sigma_S_H] ...
                = SweepA_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A_1, A_2, A_N)

            A = linspace(A_1, A_2, A_N);
            chi_q = zeros(1, length(A));
            wbar = waitbar(0, 'SimpleFit - Progress');
            for k = 1:A_N  
                [chi_q(k), I_res, S_H,  sigma_I_res, sigma_S_H] ...
                    = LSQ_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A(k));
                waitbar(k/A_N, wbar, 'SimpleFit - Progress');
            end
            [~, min_idx] = min(chi_q);
            A_opt = A(min_idx);
            close(wbar)
        
            [chi_q_opt, I_res_opt, S_H_opt, sigma_I_res, sigma_S_H] ...
                = LSQ_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A_opt);
            
        end
 
        function x_4 = OptimA_SPI_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A_1, eps)

                delta = A_1 * 0.1;

                x_1 = A_1 - delta;
                x_2 = A_1;
                x_3 = A_1 + delta;
            
                y_1 = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, x_1);
                y_2 = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, x_2);
                y_3 = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, x_3);
                k = 1;
                x_4 = x_3 + 0.5 * ((x_2 - x_3)^2 * (y_3 - y_1) + (x_1 - x_3)^2 * (y_2 - y_3))/((x_2 - x_3) * (y_3 - y_1) + (x_1 - x_3) * (y_2 - y_3));
                while abs(2 * (x_4 - x_3)/(x_4 + x_3)) > eps && k < 1000
            
                    x_1 = x_2;
                    x_2 = x_3;
                    x_3 = x_4;
            
                    y_1 = y_2;
                    y_2 = y_3;
                    y_3 = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, x_3);
            
                    x_4 = x_3 + 0.5 * ((x_2 - x_3)^2 * (y_3 - y_1) + (x_1 - x_3)^2 * (y_2 - y_3)) / ((x_2 - x_3) * (y_3 - y_1) + (x_1 - x_3) * (y_2 - y_3));
                    k = k + 1;
                end

        end

        function [I, R_H] = SANS_Model_PAR(app, q, S_H, I_res, M_s, H_0, H_dem, A)

            % Micromagnetic Model
            mu_0 = 4 * pi * 1e-7;
            H_i = H_0 - H_dem;
            l_H = sqrt((2 .* A)./(mu_0 .* M_s .* H_i));
            H_eff = H_i .* (1 + (l_H.^2) .* (q.^2));
            p = M_s ./ H_eff;
            R_H = p.^2 ./ 2;
             
            I = I_res + R_H .* S_H;

        end

        function d2_chi_dA2 = FDM2Ord_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A_opt)

            p = 0.001;
            dA = A_opt * p;
            A1 = A_opt - 2*dA;
            A2 = A_opt - 1*dA;
            A3 = A_opt;
            A4 = A_opt + 1*dA;
            A5 = A_opt + 2*dA;
        
            chi1 = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A1);
            chi2 = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A2);
            chi3 = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A3);
            chi4 = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A4);
            chi5 = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A5);
        
            d2_chi_dA2 = (-chi1 + 16 * chi2 - 30 * chi3 + 16 * chi4 - chi5)/(12 * dA^2);

        end


        % Enable and Disable functions
        function DisableFunction(app)

            app.SamplesSpinner_2.Enable = 0;
            app.q_mathrmmaxnm1EditField_2.Enable = 0;
            app.q_mathrmminnm1EditField.Enable = 0;
            app.SamplesSpinner.Enable = 0;
            app.mu_0H_0mTmaxEditField.Enable = 0;
            app.mu_0H_0mTminEditField.Enable = 0;
            app.xi_mathrmHnmEditField.Enable = 0;
            app.mu_0sqrtlangleM_mathrmz2ranglemTEditField.Enable = 0;
            app.mu_0sqrtlangleH_mathrmp2ranglemTEditField.Enable = 0;
            app.sigmacm1EditField.Enable = 0;
            app.mu_0M_0mTEditField.Enable = 0;
            app.mu_0H_mathrmdmTEditField.Enable = 0;
            app.SANSGeometryDropDown_2.Enable = 0;
            app.ApJmEditField.Enable = 0;
            app.GenSynDataButton.Enable = 0;

            app.SANSGeometryDropDown_3.Enable = 0;
            app.mu_0H_0mathrmminmTEditField_3.Enable = 0;
            app.SaveResultsButton.Enable = 0;
            app.SweepFitButton.Enable = 0;

            app.SamplesSpinner_3.Enable = 0;
            app.SANSGeometryDropDown.Enable = 0;
            app.mu_0H_0mathrmminmTEditField.Enable = 0;
            app.A_mathrmmaxpJmEditField.Enable = 0;
            app.A_mathrmminpJmEditField.Enable = 0;
            app.q_mathrmmaxnm1EditField.Enable = 0;
            app.SaveResultButton.Enable = 0;
            app.CompareResultButton.Enable = 0;
            app.SimpleFitButton.Enable = 0;

            app.InverseColorMapCheckBox.Enable = 0;
            app.ColorMapDropDown.Enable = 0;
            app.ShowErrorbarCheckBox.Enable = 0;
            app.PlotSANSDatalinButton.Enable = 0;
            app.PlotSANSDatalogButton.Enable = 0;
            app.ImportSANSDataButton.Enable = 0;
            app.b_mathrmHA1m1EditField.Enable = 0;
            
            app.q_mathrmmaxnm1EditField_3.Enable = 0;
            app.mu_0H_0mathrmminmTEditField_2.Enable = 0;
            app.A_mathrmminpJmEditField_2.Enable = 0;
            app.A_mathrmmaxpJmEditField_2.Enable = 0;
            app.SamplesSpinner_4.Enable = 0;
            app.mu_0H_mathrmdmTminEditField.Enable = 0;
            app.mu_0H_mathrmdmTmaxEditField.Enable = 0;
            app.SamplesSpinner_5.Enable = 0;
            app.SANSGeometryDropDown_4.Enable = 0;
            app.DemagFitButton.Enable = 0;

            app.xi_mathrmMnmEditField.Enable = 0;
            app.SaveResultsButton_2.Enable = 0;
            app.b_mathrmHA1m1EditField_2.Enable = 0;


        end

        function EnableFunction(app)

            app.SamplesSpinner_2.Enable = 1;
            app.q_mathrmmaxnm1EditField_2.Enable = 1;
            app.q_mathrmminnm1EditField.Enable = 1;
            app.SamplesSpinner.Enable = 1;
            app.mu_0H_0mTmaxEditField.Enable = 1;
            app.mu_0H_0mTminEditField.Enable = 1;
            app.xi_mathrmHnmEditField.Enable = 1;
            app.mu_0sqrtlangleM_mathrmz2ranglemTEditField.Enable = 1;
            app.mu_0sqrtlangleH_mathrmp2ranglemTEditField.Enable = 1;
            app.sigmacm1EditField.Enable = 1;
            app.mu_0M_0mTEditField.Enable = 1;
            app.mu_0H_mathrmdmTEditField.Enable = 1;
            app.SANSGeometryDropDown_2.Enable = 1;
            app.ApJmEditField.Enable = 1;
            app.GenSynDataButton.Enable = 1;

            app.SANSGeometryDropDown_3.Enable = 1;
            app.mu_0H_0mathrmminmTEditField_3.Enable = 1;
            app.SaveResultsButton.Enable = 1;
            app.SweepFitButton.Enable = 1;

            app.SamplesSpinner_3.Enable = 1;
            app.SANSGeometryDropDown.Enable = 1;
            app.mu_0H_0mathrmminmTEditField.Enable = 1;
            app.A_mathrmmaxpJmEditField.Enable = 1;
            app.A_mathrmminpJmEditField.Enable = 1;
            app.q_mathrmmaxnm1EditField.Enable = 1;
            app.SaveResultButton.Enable = 1;
            app.CompareResultButton.Enable = 1;
            app.SimpleFitButton.Enable = 1;

            app.InverseColorMapCheckBox.Enable = 1;
            app.ColorMapDropDown.Enable = 1;
            app.ShowErrorbarCheckBox.Enable = 1;
            app.PlotSANSDatalinButton.Enable = 1;
            app.PlotSANSDatalogButton.Enable = 1;
            app.ImportSANSDataButton.Enable = 1;
            app.b_mathrmHA1m1EditField.Enable = 1;

            app.q_mathrmmaxnm1EditField_3.Enable = 1;
            app.mu_0H_0mathrmminmTEditField_2.Enable = 1;
            app.A_mathrmminpJmEditField_2.Enable = 1;
            app.A_mathrmmaxpJmEditField_2.Enable = 1;
            app.SamplesSpinner_4.Enable = 1;
            app.mu_0H_mathrmdmTminEditField.Enable = 1;
            app.mu_0H_mathrmdmTmaxEditField.Enable = 1;
            app.SamplesSpinner_5.Enable = 1;
            app.SANSGeometryDropDown_4.Enable = 1;
            app.DemagFitButton.Enable = 1;

            app.xi_mathrmMnmEditField.Enable = 1;
            app.SaveResultsButton_2.Enable = 1;
            app.b_mathrmHA1m1EditField_2.Enable = 1;

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportSANSDataButton
        function ImportSANSDataButtonCallback(app, event)
            
            % get correct filenames
            path = uigetdir();
            if path ~= 0
                names = dir(path);            
                nameList = {};
                k = 1;
                while 1
                    try
                        N = names(k, 1).name;
                        if length(N) > 4                        
                            name_helper = names(k, 1).name;
                            if strcmp(name_helper(end-3:end), '.csv') || ...
                                    strcmp(name_helper(end-3:end), '.txt') || ...
                                    strcmp(name_helper(end-3:end), '.dat')
                                spacer_num = length(strfind(name_helper ,'_'));
                                if spacer_num == 3
                                    nameList{end+1} = name_helper;
                                end
                            end    
                        end
                        k = k + 1;
                    catch 
                        break
                    end
                end
                K = length(nameList);


                if K > 0
                    % sort the name list
                    sorted_nameList = cell(K, 1);
                    for k = 1:K
                        NameInfos = split(nameList{k}, '_'); 
                        idx = str2double(NameInfos{1});
                        sorted_nameList{idx} = nameList{k};
                    end
                    nameList = sorted_nameList;
    
                    % load data
                    B = [];
                    Ms = []; 
                    Hdem = [];
                    I = [];
                    q = [];
                    sigma = [];
                    for k = 1:K
                    dat = load([path, '/', nameList{k}]);
                    NameInfos = split(nameList{k}, '_');                
                        if length(NameInfos) == 4
                            B(end+1) = str2double(NameInfos{2});
                            Ms(end+1) = str2double(NameInfos{3});                     
                            Hdem(end+1) = str2double(NameInfos{4}(1:end-4));
                            q(:, end+1) = dat(:, 1);
                            I(:, end+1) = dat(:, 2);
                            sigma(:, end+1) = dat(:, 3);
                        end
                    end
        
                    Data.B = B;        % external field in mT
                    Data.Ms = Ms;      % magnetization in mT
                    Data.Hdem = Hdem;  % demagnetizing field in mT
                    Data.I = I;        % SANS Intensity
                    Data.q = q;        % scattering vector in 1/nm
                    Data.sigma = sigma;% standard deviation
                    
                    app.ImportSANSDataButton.UserData = Data;
                else
                    msgbox('No data found!', 'Error','error');
                end
            
            end

        end

        % Button pushed function: PlotSANSDatalogButton
        function PlotSANSDatalogButtonCallback(app, event)
            
            Data = app.ImportSANSDataButton.UserData;
            ErrBr = app.ShowErrorbarCheckBox.Value;
            cmap_name = app.ColorMapDropDown.Value;
            inv_cmap = app.InverseColorMapCheckBox.Value;
            if ~isempty(Data)
                I = Data.I;
                q = Data.q;
  
                sigma = Data.sigma;
                B = Data.B;

                CMAP = eval([cmap_name, '(length(B))']);
                if inv_cmap == 1
                    CMAP = flipud(CMAP);
                end

                fig = figure('Position', [0, 0, 900, 1000], 'Name', 'Experimental SANS Data','NumberTitle','off');
                ax = axes(fig, 'Position', [0.09, 0.09, 0.885, 0.885]);
                msize = 15; 
                lsize = 1.5;
                h = loglog(ax, q, I, 'LineStyle', '-', 'Marker', '.', 'LineWidth', lsize, 'MarkerSize', msize);
                set(h, {'Color'}, num2cell(CMAP, 2) )
                hold on 
                grid on              
                set(gca, 'FontSize', 22)
                ax.TickLabelInterpreter = 'latex';
                xlabel(ax, '$q$ [nm$^{-1}$]', 'interpreter', 'latex', 'FontSize', 25)
                ylabel(ax, '$d\Sigma/d\Omega$', 'interpreter', 'latex', 'FontSize', 25)

                if ErrBr == 1
                    h = errorbar(ax, q, I, sigma, 'LineWidth', lsize, 'MarkerSize', msize);
                    set(h, {'Color'}, num2cell(CMAP, 2) )
                end

                legendNames = {};
                for k = 1:length(B)
                    legendNames{end+1} = ['$\mu_0H_0 = ', char(string(B(k))), '$ mT'];
                end
                legend(legendNames, 'interpreter', 'latex', 'FontSize', 20)
            else
                 msgbox('There is no data available! Please import experimental data!', 'Error','error');
            end

        end

        % Button pushed function: PlotSANSDatalinButton
        function PlotSANSDatalinButtonCallback(app, event)
            

            Data = app.ImportSANSDataButton.UserData;
            ErrBr = app.ShowErrorbarCheckBox.Value;
            cmap_name = app.ColorMapDropDown.Value;
            inv_cmap = app.InverseColorMapCheckBox.Value;
            if ~isempty(Data)
                I = Data.I;
                q = Data.q;
                sigma = Data.sigma;
                B = Data.B;

                CMAP = eval([cmap_name, '(length(B))']);
                if inv_cmap == 1
                    CMAP = flipud(CMAP);
                end
                
                fig = figure('Position', [0, 0, 1000, 1000], 'Name', 'Experimental SANS Data','NumberTitle','off');
                ax = axes(fig);
                h = plot(ax, q, I, 'LineStyle', '-', 'Marker', '.');
                set(h, {'Color'}, num2cell(CMAP, 2) )
                hold on 
                grid on        
                set(gca, 'FontSize', 22)
                ax.TickLabelInterpreter = 'latex';
                xlabel(ax, '$q$ [nm$^{-1}$]', 'interpreter', 'latex', 'FontSize', 25)
                ylabel(ax, '$d\Sigma/d\Omega$', 'interpreter', 'latex', 'FontSize', 25)
                if ErrBr == 1
                    errorbar(ax, q, I, sigma)
                end

                legendNames = {};
                for k = 1:length(B)
                    legendNames{end+1} = ['$\mu_0H_0 = ', char(string(B(k))), '$ mT'];
                end
                legend(legendNames, 'interpreter', 'latex', 'FontSize', 20)
            else
                 msgbox('There is no data available! Please import experimental data!', 'Error','error');
            end

        end

        % Button pushed function: SimpleFitButton
        function SimpleFitButtonCallback(app, event)
            

            q_max = app.q_mathrmmaxnm1EditField.Value;
            B_min = app.mu_0H_0mathrmminmTEditField.Value;
            A1 = app.A_mathrmminpJmEditField.Value * 1e-12;
            A2 = app.A_mathrmmaxpJmEditField.Value * 1e-12;           
            N_A = app.SamplesSpinner_3.Value;
            SANSgeometry = app.SANSGeometryDropDown.Value;
            b_H = app.b_mathrmHA1m1EditField.Value;
            
            Data = app.ImportSANSDataButton.UserData;

            DisableFunction(app)
            if ~isempty(Data)
                if strcmp(SANSgeometry, 'perpendicular')
                    mu_0 = 4*pi*1e-7;
                    q = (Data.q * 1e9).';
                    I_exp = (Data.I).';
                    sigma = (Data.sigma).';
                    H_0 = (Data.B/mu_0*1e-3).';
                    M_s = (Data.Ms/mu_0*1e-3).';
                    H_dem = (Data.Hdem/mu_0*1e-3).';

                    % reduction the data
                    idx1 = find(q(1, :) <= q_max*1e9);
                    idx2 = find(H_0 >= B_min/mu_0*1e-3);
                    q = q(idx2, idx1);
                    I_exp = I_exp(idx2, idx1);
                    sigma = sigma(idx2, idx1);
                    H_0 = H_0(idx2);
                    M_s = M_s(idx2);
                    H_dem = H_dem(idx2);                 
    
                    [A, chi_q, A_opt, chi_q_opt, I_res_opt, S_H_opt, S_M_opt, sigma_I_res, sigma_S_H, sigma_S_M] = ...
                        SweepA_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A1, A2, N_A);
                        
                    A_opt = OptimA_SPI_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A_opt, 0.0001);
    
                    d2chi_dA2 = FDM2Ord_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem, A_opt);
    
                    N_mu = length(I_exp(1, :));
                    N_nu = length(I_exp(:, 1));
                    A_Uncertainty = sqrt(2 / (N_mu * N_nu * d2chi_dA2))*1e12;
                    if A_Uncertainty < 1e-5
                        A_Uncertainty = 0;
                    end

                    H_p = (sum(S_H_opt(1:end-1) .* q(1, 1:end-1).^2 .* diff(q(1, :))) ...
                         + sum(S_H_opt(2:end)   .* q(1, 2:end).^2   .* diff(q(1, :))))/2;
                    H_p = 10*sqrt(H_p/(2*pi^2*b_H^2)) * mu_0 * 1e3;
                   
                    M_z = (sum(S_M_opt(1:end-1) .* q(1, 1:end-1).^2 .* diff(q(1, :))) ...
                         + sum(S_M_opt(2:end)   .* q(1, 2:end).^2   .* diff(q(1, :))))/2;
                    M_z = 10*sqrt(M_z/(2*pi^2*b_H^2)) * mu_0 * 1e3;

                    fig = figure('Position', [0, 0, 1000, 800], 'Name', 'SimpleFit - Results','NumberTitle','off');
                    %ax = axes(fig);

                    ax1 = subplot(2,2,1);
                    %ax1 = axes('parent', fig, 'Position', [0.1, 0.58, 0.38, 0.38]);
                    grid on 
                    hold on 
                    plot(A*1e12, chi_q)
                    title(['$A_{\mathrm{bf}} = ' num2str(round(A_opt*1e12*1e1)*1e-1),...
                        ' \pm ' num2str(round(A_Uncertainty*1e1)*1e-1) '$ pJ/m'], ...
                        'interpreter', 'latex')
                    set(gca, 'FontSize', 20)
                    ax1.TickLabelInterpreter = 'latex';
                    xlabel('$A$ [pJ/m]', 'FontSize', 20, 'interpreter', 'latex')
                    ylabel('$\chi^2(A)$', 'FontSize', 20', 'interpreter', 'latex')
                     
                    %text(8.8, 0.29, '$(a)$', 'interpreter', 'latex', 'FontSize', 28)


                    msize = 18;
                    ax2 = subplot(2,2,2);
                    %ax2 = axes('parent', fig, 'Position', [0.59, 0.58, 0.38, 0.38]);
                    logplt = semilogx(q(1,:)*1e-9, I_res_opt, '.', 'MarkerSize', msize, 'LineWidth', msize);
                    grid on 
                    hold on 
                    herr = errorbar(ax2, q(1,:)*1e-9, I_res_opt, sigma_I_res);
                    set(herr, 'Color', logplt.Color);
           
                    set(gca, 'FontSize', 20)
                    ax2.TickLabelInterpreter = 'latex';
                    xlabel('$q$ [nm$^{-1}]$', 'FontSize', 20, 'interpreter', 'latex')
                    ylabel('$d\Sigma_{\mathrm{res}}/d\Omega$', 'interpreter', 'latex', 'FontSize', 20)
                    %text(0.155, 2300, '$(b)$', 'interpreter', 'latex', 'FontSize', 28)


                    ax3 = subplot(2,2,3);
                    %ax3 = axes('parent', fig, 'Position', [0.1, 0.07, 0.38, 0.38]);
                    logplt = semilogx(q(1,:)*1e-9, S_H_opt, '.', 'MarkerSize', msize, 'LineWidth', msize);
                    grid on 
                    hold on 
                    herr = errorbar(ax3, q(1,:)*1e-9, S_H_opt, sigma_S_H);
                    set(herr, 'Color', logplt.Color);
                    set(gca, 'FontSize', 20)
                    ax3.TickLabelInterpreter = 'latex';
                    xlabel('$q$ [nm$^{-1}]$', 'FontSize', 20, 'interpreter', 'latex')
                    ylabel('$S_{\mathrm{H}}(q)$', 'FontSize',20', 'interpreter', 'latex')
                    title(['$\mu_0\sqrt{\langle |\mathbf{H}_{\mathrm{p}}|^2 \rangle} \cong ' num2str(round(H_p)) '$ mT'], ...
                        'interpreter', 'latex')
                    %text(0.155, 0.9, '$(c)$', 'interpreter', 'latex', 'FontSize', 28)
                     
                    ax4 = subplot(2,2,4);
                    %ax4 = axes('parent', fig, 'Position', [0.59, 0.07, 0.38, 0.38]);
                    logplt = semilogx(q(1,:)*1e-9, S_M_opt, '.', 'MarkerSize', msize, 'LineWidth', msize);
                    grid on 
                    hold on 
                    herr = errorbar(ax4, q(1,:)*1e-9, S_M_opt, sigma_S_M);
                    set(herr, 'Color', logplt.Color);                    
                    set(gca, 'FontSize', 20)
                    ax4.TickLabelInterpreter = 'latex';
                    xlabel('$q$ [nm$^{-1}]$', 'FontSize', 20, 'interpreter', 'latex')
                    ylabel('$S_{\mathrm{M}}(q)$', 'FontSize', 20', 'interpreter', 'latex')
                    title(['$\mu_0\sqrt{\langle |M_{z}|^2 \rangle} \cong ' num2str(round(M_z)) '$ mT'], ...
                        'interpreter', 'latex')
                    %text(0.153, 1010, '$(d)$', 'interpreter', 'latex', 'FontSize', 28)

                    [I_fit, R_H, R_M] = SANS_Model_PERP(app, q, S_H_opt, S_M_opt, I_res_opt, M_s, H_0, H_dem, A_opt);
                    SimpleFitResult.q = q;
                    SimpleFitResult.I_fit = I_fit;
                    SimpleFitResult.I_exp = I_exp;
                    SimpleFitResult.I_res = I_res_opt;
                    SimpleFitResult.S_H = S_H_opt;
                    SimpleFitResult.S_M = S_M_opt;
                    SimpleFitResult.sigma_I_res = sigma_I_res;
                    SimpleFitResult.sigma_S_H = sigma_S_H;
                    SimpleFitResult.sigma_S_M = sigma_S_M;


                    SimpleFitResult.R_H = R_H;
                    SimpleFitResult.R_M = R_M;
                    SimpleFitResult.I_M = R_H.*S_H_opt + R_M.*S_M_opt;

                    SimpleFitResult.A = A;
                    SimpleFitResult.chi_q = chi_q;
                    SimpleFitResult.A_opt = A_opt;
                    SimpleFitResult.M_s = M_s;
                    SimpleFitResult.H_dem = H_dem;
                    SimpleFitResult.H_0 = H_0;
                    SimpleFitResult.SANSgeometry = SANSgeometry;
                    SimpleFitResult.q_max = q_max;
                    SimpleFitResult.B_min = B_min;
                    SimpleFitResult.A_opt = round(A_opt*1e12*1e2)*1e-2;
                    SimpleFitResult.A_opt_sigma = round(A_Uncertainty*1e2)*1e-2;

                    SimpleFitResult.Hp = H_p;
                    SimpleFitResult.Mz = M_z;
                    app.SimpleFitButton.UserData = SimpleFitResult;


                elseif strcmp(SANSgeometry, 'parallel')

                    mu_0 = 4*pi*1e-7;
                    q = (Data.q * 1e9).';
                    I_exp = (Data.I).';
                    sigma = (Data.sigma).';
                    H_0 = (Data.B/mu_0*1e-3).';
                    M_s = (Data.Ms/mu_0*1e-3).';
                    H_dem = (Data.Hdem/mu_0*1e-3).';
    
                    % reduction the data
                    idx1 = find(q(1, :) <= q_max*1e9);
                    idx2 = find(H_0 >= B_min/mu_0*1e-3);
                    q = q(idx2, idx1);
                    I_exp = I_exp(idx2, idx1);
                    sigma = sigma(idx2, idx1);
                    H_0 = H_0(idx2);
                    M_s = M_s(idx2);
                    H_dem = H_dem(idx2);


                    [A, chi_q, A_opt, chi_q_opt, I_res_opt, S_H_opt, sigma_I_res, sigma_S_H] = ...
                        SweepA_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A1, A2, N_A);
                        
                    A_opt = OptimA_SPI_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A_opt, 0.0001);
    
                    d2chi_dA2 = FDM2Ord_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem, A_opt);
    
                    N_mu = length(I_exp(1, :));
                    N_nu = length(I_exp(:, 1));
                    A_Uncertainty = sqrt(2 / (N_mu * N_nu * d2chi_dA2))*1e12;
   
                    H_p = (sum(S_H_opt(1:end-1) .* q(1, 1:end-1).^2 .* diff(q(1, :))) ...
                         + sum(S_H_opt(2:end)   .* q(1, 2:end).^2   .* diff(q(1, :))))/2;
                    H_p = 10*sqrt(H_p/(2*pi^2*b_H^2)) * mu_0 * 1e3;


                    fig = figure('Position', [0, 0, 1000, 800], 'Name', 'SimpleFit - Results','NumberTitle','off');
                    ax = axes(fig);

                    ax1 = subplot(2,2,1);
                    grid on 
                    hold on 
                    plot(A*1e12, chi_q)
                    title(['$A_{\mathrm{bf}} = ' num2str(round(A_opt*1e12*1e2)*1e-2),...
                        ' \pm ' num2str(round(A_Uncertainty*1e2)*1e-2) '$ pJ/m'], ...
                        'interpreter', 'latex')
                    set(gca, 'FontSize', 20)
                    ax1.TickLabelInterpreter = 'latex';
                    xlabel('$A$ [pJ/m]', 'FontSize', 20, 'interpreter', 'latex')
                    ylabel('$\chi^2(A)$', 'FontSize', 20', 'interpreter', 'latex')

                    msize = 18;
                    ax2 = subplot(2,2,2);
                    logplt = semilogx(q(1,:)*1e-9, I_res_opt, '.', 'MarkerSize', msize, 'LineWidth', msize);
                    grid on 
                    hold on 
                    herr = errorbar(ax2, q(1,:)*1e-9, I_res_opt, sigma_I_res);
                    set(herr, 'Color', logplt.Color);
                    set(gca, 'FontSize', 20)
                    ax2.TickLabelInterpreter = 'latex';
                    xlabel('$q$ [nm$^{-1}]$', 'FontSize', 20, 'interpreter', 'latex')
                    ylabel('$d\Sigma_{\mathrm{res}}/d\Omega$', 'interpreter', 'latex', 'FontSize', 20)

                    ax3 = subplot(2,2,3);
                    logplt = semilogx(q(1,:)*1e-9, S_H_opt, '.', 'MarkerSize', msize, 'LineWidth', msize);
                    grid on 
                    hold on 
                    herr = errorbar(ax3, q(1,:)*1e-9, S_H_opt, sigma_S_H);
                    set(herr, 'Color', logplt.Color);
                    set(gca, 'FontSize', 20)
                    ax3.TickLabelInterpreter = 'latex';
                    xlabel('$q$ [nm$^{-1}]$', 'FontSize', 20, 'interpreter', 'latex')
                    ylabel('$S_{\mathrm{H}}(q)$', 'FontSize', 20', 'interpreter', 'latex')
                    title(['$\mu_0\sqrt{\langle |\mathbf{H}_{\mathrm{p}}|^2 \rangle} \approx ' num2str(round(H_p*1e2)*1e-2) '$ mT'], ...
                        'interpreter', 'latex')
                     
                    [I_fit, R_H] = SANS_Model_PAR(app, q, S_H_opt, I_res_opt, M_s, H_0, H_dem, A_opt);
                    SimpleFitResult.q = q;
                    SimpleFitResult.I_fit = I_fit;
                    SimpleFitResult.I_exp = I_exp;
                    SimpleFitResult.I_res = I_res_opt;
                    SimpleFitResult.S_H = S_H_opt;
                    SimpleFitResult.R_H = R_H;
                    SimpleFitResult.I_M = R_H.*S_H_opt;
                    SimpleFitResult.A = A;
                    SimpleFitResult.chi_q = chi_q;
                    SimpleFitResult.A_opt = A_opt;
                    SimpleFitResult.M_s = M_s;
                    SimpleFitResult.H_dem = H_dem;
                    SimpleFitResult.H_0 = H_0;
                    SimpleFitResult.SANSgeometry = SANSgeometry;
                    SimpleFitResult.q_max = q_max;
                    SimpleFitResult.B_min = B_min;
                    SimpleFitResult.A_opt = round(A_opt*1e12*1e2)*1e-2;
                    SimpleFitResult.A_opt_sigma = round(A_Uncertainty*1e2)*1e-2;
                    SimpleFitResult.Hp = H_p;
                    app.SimpleFitButton.UserData = SimpleFitResult;
                    
                end
            else
                msgbox('There is no data available! Please import experimental data!', 'Error','error');
            end
            EnableFunction(app)

        end

        % Button pushed function: CompareResultButton
        function CompareResultsButtonCallback(app, event)
            
            Result = app.SimpleFitButton.UserData;
            cmap_name = app.ColorMapDropDown.Value;
            inv_cmap = app.InverseColorMapCheckBox.Value;
            if ~isempty(Result)

                q = Result.q;
                I_fit = Result.I_fit;
                I_exp = Result.I_exp;
                mu_0 = 4*pi*1e-7;
                B = Result.H_0 * mu_0 * 1e3;

                CMAP = eval([cmap_name, '(length(q(:, 1)))']);
                if inv_cmap == 1
                    CMAP = flipud(CMAP);
                end

                fig = figure('Position', [0, 0, 900, 1000], 'Name', 'Compare Fit Result and Experimental SANS Data','NumberTitle','off');
                ax = axes('parent', fig, 'Position', [0.09, 0.09, 0.885, 0.885]);
                msize = 1.5;
                h = loglog((q*1e-9).', I_fit.', 'DisplayName', 'Fit Result', 'LineWidth', msize);
                set(h, {'Color'}, num2cell(CMAP, 2) )
                
                hold on 
                grid on 
                msize = 18;
                h = loglog((q*1e-9).', I_exp.', '.', 'MarkerSize', msize, 'LineWidth', msize, 'DisplayName', 'Experimental Data');
                set(h, {'Color'}, num2cell(CMAP, 2) )
                 
                set(gca, 'FontSize', 22)
                ax.TickLabelInterpreter = 'latex';
                xlabel('$q$ [nm$^{-1}$]', 'interpreter', 'latex', 'FontSize', 25)
                ylabel('$d\Sigma/d\Omega$', 'interpreter', 'latex', 'FontSize', 25)

                legendNames = {};
                for k = 1:length(B)
                    legendNames{end+1} = ['$\mu_0H_0 = ', char(string(B(k))), '$ mT (fit)'];
                end
                for k = 1:length(B)
                    legendNames{end+1} = ['$\mu_0H_0 = ', char(string(B(k))), '$ mT (exp)'];
                end
                legend(legendNames, 'interpreter', 'latex', 'FontSize', 22)

            else
                msgbox('There is no fit result available!', 'Error','error');
            end

        end

        % Button pushed function: SaveResultButton
        function SimpleFitSaveResultButtonCallback(app, event)
            

            Result = app.SimpleFitButton.UserData;

            if ~isempty(Result)
                path = uigetdir();
                if path ~= 0

                    % make directory
                    t = now;
                    date = char(datetime(t,'ConvertFrom','datenum'));
                    d = replace(date, ':', '_');
                    d = replace(d, '-', '_');
                    d = replace(d, ' ', '_');
                    FolderName = ['SimpleFitResult_', d];
                    NewFolderDir = [path, '/', FolderName];
                    mkdir(NewFolderDir)

                    % Save Fitted Result
                    FitResultDir = [NewFolderDir, ...
                        '/Fit_Results_dSigma_dOmega'];
                    mkdir(FitResultDir)
                    mu_0 = 4*pi*1e-7;
                    q = Result.q;
                    I_fit = Result.I_fit;
                    H_0 = Result.H_0;
                    M_s = Result.M_s;
                    H_dem = Result.H_dem;
                    for k = 1:length(q(:,1))
                        writematrix([q(k, :).'*1e-9, I_fit(k, :).'], ...
                            [FitResultDir, '/' num2str(k), ...
                            '_', num2str(H_0(k)*1e3*mu_0), ...
                            '_', num2str(M_s(k)*1e3*mu_0), ...
                            '_', num2str(H_dem(k)*1e3*mu_0) , '.csv'], ...
                            'delimiter', ' ')
                    end

                    % Save Experimental SANS data
                    ExpResultDir = [NewFolderDir, ...
                        '/Experimental_dSigma_dOmega'];
                    mkdir(ExpResultDir)
                    mu_0 = 4*pi*1e-7;
                    q = Result.q;
                    I_exp = Result.I_exp;
                    H_0 = Result.H_0;
                    M_s = Result.M_s;
                    H_dem = Result.H_dem;
                    for k = 1:length(q(:,1))
                        writematrix([q(k, :).'*1e-9, I_exp(k, :).'], ...
                            [ExpResultDir, '/' num2str(k), ...
                            '_', num2str(H_0(k)*1e3*mu_0), ...
                            '_', num2str(M_s(k)*1e3*mu_0), ...
                            '_', num2str(H_dem(k)*1e3*mu_0) , '.csv'], ...
                            'delimiter', ' ')
                    end

                    %FitDir = [NewFolderDir, ...
                    %    '/FitResults'];
                    %mkdir(FitDir)

                    % Save S_H
                    S_H = Result.S_H;
                    sigma_S_H = Result.sigma_S_H;
                    writematrix([q(1,:).'*1e-9, S_H.', sigma_S_H.'], ...
                        [NewFolderDir, ...
                        '/', 'S_H.csv'], 'delimiter', ' ')


                    % Save R_H
                    ExpResultDir = [NewFolderDir, ...
                        '/Fit_Results_R_H'];
                    mkdir(ExpResultDir)
                    mu_0 = 4*pi*1e-7;
                    q = Result.q;
                    R_H = Result.R_H;
                    H_0 = Result.H_0;
                    M_s = Result.M_s;
                    H_dem = Result.H_dem;
                    for k = 1:length(q(:,1))
                        writematrix([q(k, :).'*1e-9, R_H(k, :).'], ...
                            [ExpResultDir, '/' num2str(k), ...
                            '_', num2str(H_0(k)*1e3*mu_0), ...
                            '_', num2str(M_s(k)*1e3*mu_0), ...
                            '_', num2str(H_dem(k)*1e3*mu_0) , '.csv'], ...
                            'delimiter', ' ')
                    end


                     % Save magnetic SANS intensity
                    ExpResultDir = [NewFolderDir, ...
                        '/Fit_Results_dSigma_M_dOmega'];
                    mkdir(ExpResultDir)
                    mu_0 = 4*pi*1e-7;
                    q = Result.q;
                    I_M = Result.I_M;
                    H_0 = Result.H_0;
                    M_s = Result.M_s;
                    H_dem = Result.H_dem;
                    for k = 1:length(q(:,1))
                        writematrix([q(k, :).'*1e-9, I_M(k, :).'], ...
                            [ExpResultDir, '/' num2str(k), ...
                            '_', num2str(H_0(k)*1e3*mu_0), ...
                            '_', num2str(M_s(k)*1e3*mu_0), ...
                            '_', num2str(H_dem(k)*1e3*mu_0) , '.csv'], ...
                            'delimiter', ' ')
                    end

                    % Save S_M
                    SANSgeometry = Result.SANSgeometry;
                    if strcmp(SANSgeometry, 'perpendicular')
                        S_M = Result.S_M;
                        sigma_S_M = Result.sigma_S_M;
                        writematrix([q(1,:).'*1e-9, S_M.', sigma_S_M.'], ...
                            [NewFolderDir, ...
                            '/', 'S_M.csv'], 'delimiter', ' ')  

                        % Save R_M
                        ExpResultDir = [NewFolderDir, ...
                            '/Fit_Results_R_M'];
                        mkdir(ExpResultDir)
                        mu_0 = 4*pi*1e-7;
                        q = Result.q;
                        R_M = Result.R_M;
                        H_0 = Result.H_0;
                        M_s = Result.M_s;
                        H_dem = Result.H_dem;
                        for k = 1:length(q(:,1))
                            writematrix([q(k, :).'*1e-9, R_M(k, :).'], ...
                                [ExpResultDir, '/' num2str(k), ...
                                '_', num2str(H_0(k)*1e3*mu_0), ...
                                '_', num2str(M_s(k)*1e3*mu_0), ...
                                '_', num2str(H_dem(k)*1e3*mu_0) , '.csv'], ...
                                'delimiter', ' ')
                        end
                        
                    end

                    % Save I_res
                    sigma_I_res= Result.sigma_I_res;
                    I_res = Result.I_res;
                    writematrix([q(1,:).'*1e-9, I_res.', sigma_I_res.'], ...
                        [NewFolderDir, ...
                        '/', 'dSigma_dOmega_res.csv'], 'delimiter', ' ')
                    
                    % Save chi_q 
                    chi_q = Result.chi_q;
                    A = Result.A;
                    writematrix([A.'*1e12, chi_q.'], ...
                        [NewFolderDir, ...
                        '/', 'chi.csv'], 'delimiter', ' ')

                    % Write Info File
                    fid = fopen([NewFolderDir, '/InfoFile.txt'],'wt');
                    fprintf(fid, 'FitMSANS Toolbox - SimpleFit Results Info File \n\n');
                    fprintf(fid, ['Timestamp: ', date, ' \n\n']);
                    fprintf(fid, ['SANS geometry: ', SANSgeometry, ' \n\n']);
                    fprintf(fid, ['Maximal Scattering Vector: ', num2str(Result.q_max), ' 1/nm \n']);
                    fprintf(fid, ['Minimal Applied Field: ', num2str(Result.B_min), ' mT \n\n']);
                    fprintf(fid, ['Result for the exchange stiffness constant: A = (', num2str(Result.A_opt), ' +/- ', num2str(Result.A_opt_sigma) ') pJ/m \n']);
                    fprintf(fid, ['Result for the anisotropy field: ', num2str(Result.Hp) ' mT \n']);

                    SANSgeometry = Result.SANSgeometry;
                    if strcmp(SANSgeometry, 'perpendicular')
                        fprintf(fid, ['Result for the z magnetization: ', num2str(Result.Mz) ' mT ']);                     
                    end


                    fclose(fid);


                end
            else
                msgbox('There is no fit result available!', 'Error','error');
            end
            
        end

        % Button pushed function: SweepFitButton
        function SweepFitButtonCallback(app, event)
            
            Data = app.ImportSANSDataButton.UserData;
            SANSgeometry = app.SANSGeometryDropDown_3.Value;
           
            DisableFunction(app)
            if ~isempty(Data)
                if strcmp(SANSgeometry, 'perpendicular')

                    B_min = app.mu_0H_0mathrmminmTEditField_3.Value;                
                    mu_0 = 4*pi*1e-7;
                    I_exp = (Data.I).';
                    q = (Data.q * 1e9).';
                    sigma = (Data.sigma).';
                    H_0 = (Data.B/mu_0*1e-3).';
                    M_s = (Data.Ms/mu_0*1e-3).';
                    H_dem = (Data.Hdem/mu_0*1e-3).';
                    
                    % reduction the data      
                    idx2 = find(H_0 >= B_min/mu_0*1e-3);
                    q = q(idx2, :);
                    I_exp = I_exp(idx2, :);
                    sigma = sigma(idx2, :);
                    H_0 = H_0(idx2);
                    M_s = M_s(idx2);
                    H_dem = H_dem(idx2);
    
                    K_H = length(H_0)-3;
                    K_q = length(q(1, :))-10;
                    A_opt_mat = zeros(K_H, K_q);
                    q_max_mat = zeros(K_H, K_q);
                    H_min_mat = zeros(K_H, K_q);
    
                    wbar = waitbar(0, 'SweepFit - Progress');
                    steps = K_H * K_q;
                    step = 1;
                    for i = 1:K_H
                        for j = 1:K_q
                            
                            q_sub     =     q(i:end, 1:9+j);
                            I_exp_sub = I_exp(i:end, 1:9+j);
                            sigma_sub = sigma(i:end, 1:9+j);
                            M_s_sub   =   M_s(i:end);
                            H_0_sub   =   H_0(i:end);
                            H_dem_sub = H_dem(i:end);
                            A_1 = 5e-12;
                            eps = 0.001;
    
    
                            A_opt_mat(i, j) = OptimA_SPI_PERP(app, q_sub, ...
                                                                   I_exp_sub, ...
                                                                   sigma_sub, ...
                                                                   M_s_sub, ...
                                                                   H_0_sub, ...
                                                                   H_dem_sub, ...
                                                                   A_1, ...
                                                                   eps);
                              
                            q_max_mat(i, j) = q(1, 9+j)*1e-9;
                            H_min_mat(i, j) = H_0(i)*mu_0*1e3;
                            waitbar(step/steps, wbar, 'SweepFit - Progress');
                            step = step + 1;
                        end
                    end
                    close(wbar)
         
                    A_opt_mat(isnan(A_opt_mat)) = 0;
                    A_opt_mat(isinf(A_opt_mat)) = 0;
                    A_opt_mat(A_opt_mat<0) = 0;
    
                    if ~isempty(find(A_opt_mat==0))
                        warndlg('Some fits failed and set by default to zero!', 'Warning!');
                    end

                    fig = figure('Position', [0, 0, 1000, 1000], 'Name', 'SweepFit - Result','NumberTitle','off');
                    ax = axes('Parent', fig);
                    hold on 
                    grid on 
                    surf(H_min_mat, q_max_mat, A_opt_mat*1e12)
                    xlabel('$\mu_0H_{0}^{\mathrm{min}}$ [mT]', ...
                        'interpreter', 'latex',...
                        'FontSize', 15)
                    ylabel('$q_{\mathrm{max}}$ [1/nm]', ...
                        'interpreter', 'latex', ...
                        'FontSize', 15)
                    zlabel('$A_{\mathrm{bf}}$ [pJ/m]', ...
                        'interpreter', 'latex', ...
                        'FontSize', 15)
                    view(48, 22)
                    ax.TickLabelInterpreter = 'latex';
                    set(gca, 'FontSize', 16)

                    SweepFitResults.A_opt_mat = A_opt_mat;
                    SweepFitResults.q_max_mat = q_max_mat;
                    SweepFitResults.H_min_mat = H_min_mat;
                    SweepFitResults.SANSgeometry = SANSgeometry;


                    app.SweepFitButton.UserData = SweepFitResults;


                elseif strcmp(SANSgeometry, 'parallel')

                    B_min = app.mu_0H_0mathrmminmTEditField_3.Value;                
                    mu_0 = 4*pi*1e-7;
                    I_exp = (Data.I).';
                    q = (Data.q * 1e9).';
                    sigma = (Data.sigma).';
                    H_0 = (Data.B/mu_0*1e-3).';
                    M_s = (Data.Ms/mu_0*1e-3).';
                    H_dem = (Data.Hdem/mu_0*1e-3).';
                    
                    % reduction the data      
                    idx2 = find(H_0 >= B_min/mu_0*1e-3);
                    q = q(idx2, :);
                    I_exp = I_exp(idx2, :);
                    sigma = sigma(idx2, :);
                    H_0 = H_0(idx2);
                    M_s = M_s(idx2);
                    H_dem = H_dem(idx2);
    
                    K_H = length(H_0)-3;
                    K_q = length(q(1, :))-10;
                    A_opt_mat = zeros(K_H, K_q);
                    q_max_mat = zeros(K_H, K_q);
                    H_min_mat = zeros(K_H, K_q);
    
                    wbar = waitbar(0, 'SweepFit - Progress');
                    steps = K_H * K_q;
                    step = 1;
                    for i = 1:K_H
                        for j = 1:K_q
                            
                            q_sub     =     q(i:end, 1:9+j);
                            I_exp_sub = I_exp(i:end, 1:9+j);
                            sigma_sub = sigma(i:end, 1:9+j);
                            M_s_sub   =   M_s(i:end);
                            H_0_sub   =   H_0(i:end);
                            H_dem_sub = H_dem(i:end);
                            A_1 = 5e-12;
                            eps = 0.001;
        
                            A_opt_mat(i, j) = OptimA_SPI_PAR(app, q_sub, ...
                                                                   I_exp_sub, ...
                                                                   sigma_sub, ...
                                                                   M_s_sub, ...
                                                                   H_0_sub, ...
                                                                   H_dem_sub, ...
                                                                   A_1, ...
                                                                   eps);
                              
                            q_max_mat(i, j) = q(1, 9+j)*1e-9;
                            H_min_mat(i, j) = H_0(i)*mu_0*1e3;                            
                            waitbar(step/steps, wbar, 'SweepFit - Progress');
                            step = step + 1;
                        end
                    end
                    close(wbar)
    
                    A_opt_mat(isnan(A_opt_mat)) = 0;
                    A_opt_mat(isinf(A_opt_mat)) = 0;
                    A_opt_mat(A_opt_mat<0) = 0;
    
                    if ~isempty(find(A_opt_mat==0))
                        warndlg('Some fits failed and set by default to zero!', 'Warning!');
                    end

                    fig = figure('Position', [0, 0, 1000, 1000], 'Name', 'SweepFit - Result','NumberTitle','off');
                    ax = axes('parent', fig);
                    hold on 
                    grid on 
                    surf(H_min_mat, q_max_mat, A_opt_mat*1e12)
                    xlabel('$\mu_0H_{0}^{\mathrm{min}}$ [mT]', ...
                        'interpreter', 'latex', ...
                        'FontSize',15)
                    ylabel('$q_{\mathrm{max}}$ [1/nm]', ...
                        'interpreter', 'latex', ...
                        'FontSize', 15)
                    zlabel('$A_{\mathrm{bf}}$ [pJ/m]', ...
                        'interpreter', 'latex', ...
                        'FontSize', 15)
                    view(48, 22)
                    ax.TickLabelInterpreter = 'latex';
                    set(gca, 'FontSize', 16)


                    SweepFitResults.A_opt_mat = A_opt_mat;
                    SweepFitResults.q_max_mat = q_max_mat;
                    SweepFitResults.H_min_mat = H_min_mat;
                    SweepFitResults.SANSgeometry = SANSgeometry;

                    app.SweepFitButton.UserData = SweepFitResults;

                end


            else
                msgbox('There is no data available! Please import experimental data!', 'Error','error');
            end
            EnableFunction(app)
    
        end

        % Button pushed function: SaveResultsButton
        function SweepFitSaveResultsButtonCallback(app, event)
            
            Results = app.SweepFitButton.UserData;

            if ~isempty(Results)
                path = uigetdir();
                if path ~= 0

                    % make directory
                    t = now;
                    date = char(datetime(t,'ConvertFrom','datenum'));
                    d = replace(date, ':', '_');
                    d = replace(d, '-', '_');
                    d = replace(d, ' ', '_');
                    FolderName = ['SweepFitResult_', d];
                    NewFolderDir = [path, '/', FolderName];
                    mkdir(NewFolderDir)


                    A_opt_mat = Results.A_opt_mat;
                    q_max_mat = Results.q_max_mat;
                    H_min_mat = Results.H_min_mat;
                    SANSgeometry = Results.SANSgeometry;

                    writematrix(A_opt_mat, [NewFolderDir,...
                        '/A_opt_mat.csv'], 'delimiter', ' ')
                    writematrix(q_max_mat, [NewFolderDir,...
                        '/q_max_mat.csv'], 'delimiter', ' ')
                    writematrix(H_min_mat, [NewFolderDir,...
                        '/H_min_mat.csv'], 'delimiter', ' ')


                    % Write Info File
                    fid = fopen([NewFolderDir, '/InfoFile.txt'],'wt');
                    fprintf(fid, 'FitMSANS Toolbox - SimpleFit Results Info File \n\n');
                    fprintf(fid, ['Timestamp: ', date, ' \n\n']);
                    fprintf(fid, ['SANS geometry: ', SANSgeometry, ' \n\n']);                   
                    fclose(fid);

                end
            else
                msgbox('There is no data available!', 'Error','error');
            end

        end

        % Button pushed function: GenSynDataButton
        function GenSynDataButtonCallback(app, event)
            

            mu_0 = 4*pi*1e-7;
            H_p = app.mu_0sqrtlangleH_mathrmp2ranglemTEditField.Value/mu_0 * 1e-3;
            M_z = app.mu_0sqrtlangleM_mathrmz2ranglemTEditField.Value/mu_0 * 1e-3;
            xi_H = app.xi_mathrmHnmEditField.Value * 1e-9;
            xi_M = app.xi_mathrmMnmEditField.Value * 1e-9;
            A = app.ApJmEditField.Value * 1e-12;
            H_dem = (app.mu_0H_mathrmdmTEditField.Value/mu_0 * 1e-3);
            M_s = (app.mu_0M_0mTEditField.Value/mu_0 * 1e-3);
            sigma = app.sigmacm1EditField.Value;
            b_H = app.b_mathrmHA1m1EditField_2.Value;

            a_H = 8*pi*b_H^2*xi_H^3 * H_p^2;
            a_M = 8*pi*b_H^2*xi_M^3 * M_z^2;

            H1 = app.mu_0H_0mTminEditField.Value/mu_0 * 1e-3;
            H2 = app.mu_0H_0mTmaxEditField.Value/mu_0 * 1e-3;
            N_B = app.SamplesSpinner.Value;

            q1 = app.q_mathrmminnm1EditField.Value * 1e9;
            q2 = app.q_mathrmmaxnm1EditField_2.Value * 1e9;
            N_q = app.SamplesSpinner_2.Value;

            SANSgeometry = app.SANSGeometryDropDown_2.Value;
            
            [q, H_0] = meshgrid(linspace(q1, q2, N_q), linspace(H1, H2, N_B));


            path = uigetdir();
            if path ~= 0       
                if strcmp(SANSgeometry, 'perpendicular')

                    [I_sim, I_res, S_H, S_M] = ...
                        LorentzianModelPERP(app, q, A, M_s, H_0, H_dem, a_H, a_M, xi_H, xi_M);
                    
                    % make directory
                    t = now;
                    date = char(datetime(t,'ConvertFrom','datenum'));
                    d = replace(date, ':', '_');
                    d = replace(d, '-', '_');
                    d = replace(d, ' ', '_');
                    FolderName = ['SyntheticSANSdata_', d];
                    NewFolderDir = [path, '/', FolderName];
                    mkdir(NewFolderDir)

                    % make directory 2
                    FolderName2 = [NewFolderDir, '/SANS_intensity'];
                    mkdir(FolderName2)
                    mu_0 = 4*pi*1e-7;   
                   
                    for k = 1:length(q(:,1))
                        writematrix([q(k, :).'*1e-9, I_sim(k, :).', (I_sim(k, :)*0).'+sigma], ...
                            [FolderName2, '/' num2str(k), ...
                            '_', num2str(H_0(k,1)*1e3*mu_0), ...
                            '_', num2str(M_s*1e3*mu_0), ...
                            '_', num2str(H_dem*1e3*mu_0) , '.csv'], ...
                            'delimiter', ' ')
                    end

                    % Save I_res
                    writematrix([q(1,:).'*1e-9, I_res(1,:).'], ...
                        [NewFolderDir, ...
                        '/', 'dSigma_dOmega_res.csv'], 'delimiter', ' ')
                   
                    % Save S_H                    
                    writematrix([q(1,:).'*1e-9, S_H(1,:).'], ...
                        [NewFolderDir, ...
                        '/', 'S_H.csv'], 'delimiter', ' ')
                    
                    % Save S_M                    
                    writematrix([q(1,:).'*1e-9, S_M(1,:).'], ...
                        [NewFolderDir, ...
                        '/', 'S_M.csv'], 'delimiter', ' ')                    
                   
                    % Write Info File
                    fid = fopen([NewFolderDir, '/InfoFile.txt'],'wt');
                    fprintf(fid, 'FitMSANS Toolbox - SimpleFit Results Info File \n\n');
                    fprintf(fid, ['Timestamp: ', date, ' \n\n']);
                    fprintf(fid, ['SANS geometry: ', SANSgeometry, ' \n\n']);
                    fprintf(fid, ['Model Parameters: \n']);
                    fprintf(fid, ['Saturation Magnetization     : M_0   = ', num2str(M_s*mu_0*1e3), ' mT \n']);
                    fprintf(fid, ['Demagnetizing Field          : H_dem = ', num2str(H_dem*mu_0*1e3), ' mT \n']);
                    fprintf(fid, ['Exchange Stiffness Constant  : A     = ', num2str(A*1e12), ' pJ/m \n']);
                    fprintf(fid, ['Lorentzian Coefficient       : H_p   = ', num2str(H_p*mu_0*1e3), ' mT \n']);
                    fprintf(fid, ['Lorentzian Coefficient       : M_z   = ', num2str(M_z*mu_0*1e3), ' mT \n']);
                    fprintf(fid, ['Lorentzian Coefficient       : xi_H  = ', num2str(xi_H*1e9), ' nm \n']);
                    fprintf(fid, ['Lorentzian Coefficient       : xi_M  = ', num2str(xi_M*1e9), ' nm \n']);
                    fprintf(fid, ['Standard Deviation           : sigma = ', num2str(sigma), '1/cm']);
                    fclose(fid);


                elseif strcmp(SANSgeometry, 'parallel')

                    [I_sim, I_res, S_H] = ...
                        LorentzianModelPAR(app, q, A, M_s, H_0, H_dem, a_H, xi_H);
                     
                    % make directory
                    t = now;
                    date = char(datetime(t,'ConvertFrom','datenum'));
                    d = replace(date, ':', '_');
                    d = replace(d, '-', '_');
                    d = replace(d, ' ', '_');
                    FolderName = ['SyntheticSANSdata_', d];
                    NewFolderDir = [path, '/', FolderName];
                    mkdir(NewFolderDir)

                    % make directory 2
                    FolderName2 = [NewFolderDir, '/SANS_intensity'];
                    mkdir(FolderName2)
                    mu_0 = 4*pi*1e-7;                     
                    for k = 1:length(q(:,1))
                        writematrix([q(k, :).'*1e-9, I_sim(k, :).', (I_sim(k, :)*0).'+sigma], ...
                            [FolderName2, '/' num2str(k), ...
                            '_', num2str(H_0(k,1)*1e3*mu_0), ...
                            '_', num2str(M_s*1e3*mu_0), ...
                            '_', num2str(H_dem*1e3*mu_0) , '.csv'], ...
                            'delimiter', ' ')
                    end

                    % Save I_res
                    writematrix([q(1,:).'*1e-9, I_res(1,:).'], ...
                        [NewFolderDir, ...
                        '/', 'dSigma_dOmega_res.csv'], 'delimiter', ' ')
                   
                    % Save S_H                    
                    writematrix([q(1,:).'*1e-9, S_H(1,:).'], ...
                        [NewFolderDir, ...
                        '/', 'S_H.csv'], 'delimiter', ' ')
                 
                    % Write Info File
                    fid = fopen([NewFolderDir, '/InfoFile.txt'],'wt');
                    fprintf(fid, 'FitMSANS Toolbox - SimpleFit Results Info File \n\n');
                    fprintf(fid, ['Timestamp: ', date, ' \n\n']);
                    fprintf(fid, ['SANS geometry: ', SANSgeometry, ' \n\n']);
                    fprintf(fid, ['Model Parameters: \n']);
                    fprintf(fid, ['Saturation Magnetization     : M_0   = ', num2str(M_s*mu_0*1e3), ' mT \n']);
                    fprintf(fid, ['Demagnetizing Field          : H_dem = ', num2str(H_dem*mu_0*1e3), ' mT \n']);
                    fprintf(fid, ['Exchange Stiffness Constant  : A     = ', num2str(A*1e12), ' pJ/m \n']);
                    fprintf(fid, ['Lorentzian Coefficient       : H_p   = ', num2str(H_p*mu_0*1e3), ' mT \n']);
                    fprintf(fid, ['Lorentzian Coefficient       : xi_H  = ', num2str(xi_H*1e9), ' nm \n']);
                    fprintf(fid, ['Standard Deviation           : sigma = ', num2str(sigma)]);
                    fclose(fid);

                end
            end


        end

        % Value changed function: A_mathrmmaxpJmEditField
        function SimpleFitAmaxEditCallback(app, event)
            value_max = app.A_mathrmmaxpJmEditField.Value;
            value_min = app.A_mathrmminpJmEditField.Value;

            if value_max < value_min
                msgbox('The max value must be larger than the min value!', 'Error','error');
                app.A_mathrmminpJmEditField.Value = 1;
                app.A_mathrmmaxpJmEditField.Value = 20;
            end

        end

        % Value changed function: A_mathrmminpJmEditField
        function SimpleFitAminEditCallback(app, event)
            value_max = app.A_mathrmmaxpJmEditField.Value;
            value_min = app.A_mathrmminpJmEditField.Value;

            if value_max < value_min
                msgbox('The max value must be larger than the min value!', 'Error','error');
                app.A_mathrmminpJmEditField.Value = 1;
                app.A_mathrmmaxpJmEditField.Value = 20;
            end
            
        end

        % Value changed function: mu_0H_0mTminEditField
        function mu0Hmin_minEditCallback(app, event)
            value_min = app.mu_0H_0mTminEditField.Value;
            value_max = app.mu_0H_0mTmaxEditField.Value;

            if value_max < value_min
                msgbox('The max value must be larger than the min value!', 'Error','error');
                app.mu_0H_0mTminEditField.Value = 200;
                app.mu_0H_0mTmaxEditField.Value = 1600;
            end

        end

        % Value changed function: mu_0H_0mTmaxEditField
        function mu0Hmin_maxEditCallback(app, event)
            value_min = app.mu_0H_0mTminEditField.Value;
            value_max = app.mu_0H_0mTmaxEditField.Value;

            if value_max < value_min
                msgbox('The max value must be larger than the min value!', 'Error','error');
                app.mu_0H_0mTminEditField.Value = 200;
                app.mu_0H_0mTmaxEditField.Value = 1600;
            end
            
        end

        % Value changed function: q_mathrmminnm1EditField
        function qmin_EditCallback(app, event)
            value_min = app.q_mathrmminnm1EditField.Value;
            value_max = app.q_mathrmmaxnm1EditField_2.Value;

            if value_max < value_min
                msgbox('The max value must be larger than the min value!', 'Error','error');
                app.q_mathrmminnm1EditField.Value = 0.01;
                app.q_mathrmmaxnm1EditField_2.Value = 0.8;
            end
            
        end

        % Value changed function: q_mathrmmaxnm1EditField_2
        function qmax_EditCallback(app, event)
            value_min = app.q_mathrmminnm1EditField.Value;
            value_max = app.q_mathrmmaxnm1EditField_2.Value;

            if value_max < value_min
                msgbox('The max value must be larger than the min value!', 'Error','error');
                app.q_mathrmminnm1EditField.Value = 0.01;
                app.q_mathrmmaxnm1EditField_2.Value = 0.8;
            end           
        end

        % Button pushed function: DemagFitButton
        function DemagFitButtonCallback(app, event)
            
            mu_0 = 4*pi*1e-7;
            q_max = app.q_mathrmmaxnm1EditField_3.Value;
            B_min = app.mu_0H_0mathrmminmTEditField_2.Value;
            A1 = app.A_mathrmminpJmEditField_2.Value * 1e-12;
            A2 = app.A_mathrmmaxpJmEditField_2.Value * 1e-12;           
            N_A = app.SamplesSpinner_4.Value;
            H_dem1 = app.mu_0H_mathrmdmTminEditField.Value/mu_0 * 1e-3;
            H_dem2 = app.mu_0H_mathrmdmTmaxEditField.Value/mu_0 * 1e-3;
            N_dem = app.SamplesSpinner_5.Value;
            SANSgeometry = app.SANSGeometryDropDown_4.Value;
            
            Data = app.ImportSANSDataButton.UserData;

            DisableFunction(app)
            if ~isempty(Data)
                if strcmp(SANSgeometry, 'perpendicular')
                    
                    q = (Data.q * 1e9).';
                    I_exp = (Data.I).';
                    sigma = (Data.sigma).';
                    H_0 = (Data.B/mu_0*1e-3).';
                    M_s = (Data.Ms/mu_0*1e-3).';

                    % reduction the data
                    idx1 = find(q(1, :) <= q_max*1e9);
                    idx2 = find(H_0 >= B_min/mu_0*1e-3);
                    q = q(idx2, idx1);
                    I_exp = I_exp(idx2, idx1);
                    sigma = sigma(idx2, idx1);
                    H_0 = H_0(idx2);
                    M_s = M_s(idx2);
    
                   
                    [A_mat, H_dem_mat] = meshgrid(linspace(A1, A2, N_A), linspace(H_dem1, H_dem2, N_dem));

                    chi_q_mat = A_mat * 0;
                    wbar = waitbar(0, 'DemagFit - Progress');
                    step = 1; 
                    steps = N_A * N_dem;
                    for i = 1:N_dem
                        for j = 1:N_A
                            chi_q_mat(i, j) = chi_q_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem_mat(i, j), A_mat(i, j));
                            waitbar(step/steps, wbar, 'DemagFit - Progress');
                            step = step + 1;
                        end
                    end
                    close(wbar);                    


                    chi_q_min =  min(min(chi_q_mat));
                    [idx_a, idx_b] = find(chi_q_mat==chi_q_min);

                    H_dem_fit = H_dem_mat(idx_a, idx_b);
                    A_fit = A_mat(idx_a, idx_b);

                    fig = figure('Name', 'DemagFit - Result','NumberTitle','off');
                    ax = axes('parent', fig);
                    hold on 
                    grid on 
                    imagesc(ax, [min(min(A_mat)), max(max(A_mat))]*1e12, [min(min(H_dem_mat)), max(max(H_dem_mat))]*mu_0*1e3, log10(chi_q_mat))
                    
                    set(gca, 'FontSize', 15)
                    ax.TickLabelInterpreter = 'latex';

                    A_min = round(A_fit*1e12*10)/10;
                    H_dem_min = round(H_dem_fit*mu_0*1e3*10)/10;
                    xlabel('$A$ [pJ/m]', 'interpreter', 'latex', 'FontSize', 15) 
                    ylabel('$\mu_0 H_{\mathrm{d}}$ [mT]', 'interpreter', 'latex', 'FontSize', 15) 
                    title(['$A_{\mathrm{bf}} = ' num2str(A_min), '$ pJ/m, $\mu_0H_{\mathrm{d}}^{\mathrm{bf}} = ' num2str(H_dem_min), '$ mT'], ...
                        'interpreter', 'latex', 'FontSize', 17)
                    cbr = colorbar;
                    cbr.Label.String = '$\log_{10}(\chi^2)$';
                    cbr.Label.Interpreter = 'latex';
                    cbr.Label.FontSize = 18;
                    cbr.TickLabelInterpreter = 'latex';
                    axis tight
                    
                    xl = ax.XLim;
                    yl = ax.YLim;

                    plot([A_min, A_min], [yl(1), H_dem_min], 'r', 'linewidth', 1)
                    plot([xl(1), A_min], [H_dem_min, H_dem_min], 'r', 'linewidth', 1)
                    scatter(A_min, H_dem_min, 80, 'r', 'linewidth', 1)

                    [chi_q, I_res_opt, S_H_opt, S_M_opt, sigma_I_res, sigma_S_H, sigma_S_M] = ...
                                LSQ_PERP(app, q, I_exp, sigma, M_s, H_0, H_dem_fit, A_fit);

                    [I_fit, R_H, R_M] = SANS_Model_PERP(app, q, S_H_opt, S_M_opt, I_res_opt, M_s, H_0, H_dem_fit, A_fit);
                    
                    DemagFitResult.H_dem = H_dem_fit*mu_0*1e-3;
                    DemagFitResult.A = A_fit * 1e12;
                    DemagFitResult.A_mat = A_mat * 1e12;
                    DemagFitResult.H_dem_mat = H_dem_mat*mu_0*1e-3;
                    DemagFitResult.chi_q_mat = chi_q_mat;
                    DemagFitResult.I_fit = I_fit;
                    DemagFitResult.I_exp = I_exp;
                    DemagFitResult.q = q;
                    DemagFitResult.sigma = sigma;
                    DemagFitResult.H_0 = H_0;
                    DemagFitResult.M_s = M_s;
                    DemagFitResult.S_H = S_H_opt;
                    DemagFitResult.S_M = S_M_opt;
                    DemagFitResult.I_res = I_res_opt;
                    DemagFitResult.R_H = R_H;
                    DemagFitResult.R_M = R_M;
       

                    app.DemagFitButton.UserData = DemagFitResult;

                elseif strcmp(SANSgeometry, 'parallel')

                    mu_0 = 4*pi*1e-7;
                    q = (Data.q * 1e9).';
                    I_exp = (Data.I).';
                    sigma = (Data.sigma).';
                    H_0 = (Data.B/mu_0*1e-3).';
                    M_s = (Data.Ms/mu_0*1e-3).';                     
    
                    % reduction the data
                    idx1 = find(q(1, :) <= q_max*1e9);
                    idx2 = find(H_0 >= B_min/mu_0*1e-3);
                    q = q(idx2, idx1);
                    I_exp = I_exp(idx2, idx1);
                    sigma = sigma(idx2, idx1);
                    H_0 = H_0(idx2);
                    M_s = M_s(idx2);                 

                    [A_mat, H_dem_mat] = meshgrid(linspace(A1, A2, N_A), linspace(H_dem1, H_dem2, N_dem));

                    chi_q_mat = A_mat * 0;
                    wbar = waitbar(0, 'DemagFit - Progress');
                    step = 1; 
                    steps = N_A * N_dem;
                    for i = 1:N_dem
                        for j = 1:N_A
                            chi_q_mat(i, j) = chi_q_PAR(app, q, I_exp, sigma, M_s, H_0, H_dem_mat(i, j), A_mat(i, j));
                            waitbar(step/steps, wbar, 'DemagFit - Progress');
                            step = step + 1;
                        end
                    end
                    close(wbar);
                     
                    chi_q_min =  min(min(chi_q_mat));
                    [idx_a, idx_b] = find(chi_q_mat==chi_q_min);

                    H_dem_fit = H_dem_mat(idx_a, idx_b);
                    A_fit = A_mat(idx_a, idx_b);

                    fig = figure('Name', 'DemagFit Result','NumberTitle','off');
                    ax = axes('parent', fig);
                    hold on 
                    grid on 
                    imagesc(ax, [min(min(A_mat)), max(max(A_mat))]*1e12, [min(min(H_dem_mat)), max(max(H_dem_mat))]*mu_0*1e3, log10(chi_q_mat))
                    xlabel('$A$ [pJ/m]', 'interpreter', 'latex', 'FontSize', 15) 
                    ylabel('$\mu_0 H_{\mathrm{d}}$ [mT]', 'interpreter', 'latex', 'FontSize', 15) 

                    set(gca, 'FontSize', 15)
                    ax.TickLabelInterpreter = 'latex';

                    A_min = round(A_fit*1e12*10)/10;
                    H_dem_min = round(H_dem_fit*mu_0*1e3*10)/10;
                    title(['$A_{\mathrm{bf}} = ' num2str(A_min), '$ pJ/m, $\mu_0H_{\mathrm{d}}^{\mathrm{bf}} = ' num2str(H_dem_min), '$ mT'], ...
                        'interpreter', 'latex', 'FontSize', 17)
                    cbr = colorbar;
                    cbr.Label.String = '$\log_{10}(\chi^2)$';
                    cbr.Label.Interpreter = 'latex';
                    cbr.Label.FontSize = 18;
                    cbr.TickLabelInterpreter = 'latex';
                    axis tight
                    
                    xl = ax.XLim;
                    yl = ax.YLim;
                    
                    
                    plot([A_min, A_min], [yl(1), H_dem_min], 'r', 'linewidth', 1)
                    plot([xl(1), A_min], [H_dem_min, H_dem_min], 'r', 'linewidth', 1)
                    scatter(A_min, H_dem_min, 60, 'r', 'linewidth', 1)

                    DemagFitResult.H_dem = H_dem_fit*mu_0*1e-3;
                    DemagFitResult.A = A_fit * 1e12;
                    DemagFitResult.A_mat = A_mat * 1e12;
                    DemagFitResult.H_dem_mat = H_dem_mat*mu_0*1e-3;
                    DemagFitResult.chi_q_mat = chi_q_mat;
                    DemagFitResult.I_exp = I_exp;
                    DemagFitResult.q = q;
                    DemagFitResult.sigma = sigma;
                    DemagFitResult.H_0 = H_0;
                    DemagFitResult.M_s = M_s;

                    app.DemagFitButton.UserData = DemagFitResult;
                          
                end
            else
                msgbox('There is no data available! Please import experimental data!', 'Error','error');
            end
            EnableFunction(app)
            

        end

        % Value changed function: A_mathrmminpJmEditField_2
        function DemagFitAminEditCallback(app, event)
            value_min = app.A_mathrmminpJmEditField_2.Value;
            value_max = app.A_mathrmmaxpJmEditField_2.Value;

            if value_max < value_min
                msgbox('The max value must be larger than the min value!', 'Error','error');
                app.A_mathrmminpJmEditField_2.Value = 1;
                app.A_mathrmmaxpJmEditField_2.Value = 20;
            end      

        end

        % Value changed function: A_mathrmmaxpJmEditField_2
        function DemagFitAmaxEditCallback(app, event)
            value_min = app.A_mathrmminpJmEditField_2.Value;
            value_max = app.A_mathrmmaxpJmEditField_2.Value;

            if value_max < value_min
                msgbox('The max value must be larger than the min value!', 'Error','error');
                app.A_mathrmminpJmEditField_2.Value = 1;
                app.A_mathrmmaxpJmEditField_2.Value = 20;
            end    

        end

        % Value changed function: mu_0H_mathrmdmTminEditField
        function DemagFitHdemminEditCallback(app, event)
            
            value_min = app.mu_0H_mathrmdmTminEditField.Value;
            value_max = app.mu_0H_mathrmdmTmaxEditField.Value;

            if value_max < value_min
                msgbox('The max value must be larger than the min value!', 'Error','error');
                app.mu_0H_mathrmdmTminEditField.Value = 0;
                app.mu_0H_mathrmdmTmaxEditField.Value = 20;
            end  

        end

        % Value changed function: mu_0H_mathrmdmTmaxEditField
        function DemagFitHdemmaxEditCallback(app, event)
            
            value_min = app.mu_0H_mathrmdmTminEditField.Value;
            value_max = app.mu_0H_mathrmdmTmaxEditField.Value;

            if value_max < value_min
                msgbox('The max value must be larger than the min value!', 'Error','error');
                app.mu_0H_mathrmdmTminEditField.Value = 0;
                app.mu_0H_mathrmdmTmaxEditField.Value = 20;
            end  
            
        end

        % Button pushed function: SaveResultsButton_2
        function DemagFitSaveButtonCallback(app, event)
            
            Result = app.DemagFitButton.UserData;

            if ~isempty(Result)
                path = uigetdir();
                if path ~= 0 
    
                    A_mat = Result.A_mat;
                    H_dem_mat = Result.H_dem_mat;
                    chi_q_mat = Result.chi_q_mat;
                    I_exp = Result.I_exp;
                    q = Result.q;
                    sigma = Result.sigma;

                     % make directory
                    t = now;
                    date = char(datetime(t,'ConvertFrom','datenum'));
                    d = replace(date, ':', '_');
                    d = replace(d, '-', '_');
                    d = replace(d, ' ', '_');
                    FolderName = ['DemagFitResults_', d];
                    NewFolderDir = [path, '/', FolderName];
                    mkdir(NewFolderDir)

                    % Save A-matrix
                    writematrix(A_mat, ...
                        [NewFolderDir,  '/', 'A.csv'], 'delimiter', ' ')

                    % Save H_dem-matrix
                    writematrix(H_dem_mat, ...
                        [NewFolderDir,  '/', 'H_d.csv'], 'delimiter', ' ')

                    % Save chi_q-matrix
                    writematrix(chi_q_mat, ...
                        [NewFolderDir,  '/', 'chi.csv'], 'delimiter', ' ')                   

                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MuMagToolboxNanoMagnetismGroupUniversityofLuxembourgUIFigure = uifigure('Visible', 'off');
            app.MuMagToolboxNanoMagnetismGroupUniversityofLuxembourgUIFigure.Name = 'MuMag2022 – Toolbox (NanoMagnetism Group – University of Luxembourg)';
            app.MuMag2022ToolboxNanoMagnetismGroupUniversityofLuxembourgPanel = uipanel(app.MuMagToolboxNanoMagnetismGroupUniversityofLuxembourgUIFigure);
            app.MuMag2022ToolboxNanoMagnetismGroupUniversityofLuxembourgPanel.Title = 'MuMag2022 – Toolbox (NanoMagnetism Group – University of Luxembourg)';
            app.ImportExperimentalDataPanel = uipanel(app.MuMag2022ToolboxNanoMagnetismGroupUniversityofLuxembourgPanel);
            app.ImportExperimentalDataPanel.Title = '---Import Experimental Data';
            app.ImportSANSDataButton = uibutton(app.ImportExperimentalDataPanel, 'push');
            app.ImportSANSDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSANSDataButtonCallback, true);
            app.ImportSANSDataButton.Text = 'Import SANS Data';
            app.PlotSANSDatalogButton = uibutton(app.ImportExperimentalDataPanel, 'push');
            app.PlotSANSDatalogButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSANSDatalogButtonCallback, true);
            app.PlotSANSDatalogButton.Text = 'Plot SANS Data (log)';
            app.PlotSANSDatalinButton = uibutton(app.ImportExperimentalDataPanel, 'push');
            app.PlotSANSDatalinButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSANSDatalinButtonCallback, true);
            app.PlotSANSDatalinButton.Text = 'Plot SANS Data (lin)';
            app.ShowErrorbarCheckBox = uicheckbox(app.ImportExperimentalDataPanel);
            app.ShowErrorbarCheckBox.Text = 'Show Errorbar';
            app.ColorMapDropDownLabel = uilabel(app.ImportExperimentalDataPanel);
            app.ColorMapDropDownLabel.Text = 'ColorMap';
            app.ColorMapDropDown = uidropdown(app.ImportExperimentalDataPanel);
            app.ColorMapDropDown.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet'};
            app.ColorMapDropDown.Value = 'parula';
            app.InverseColorMapCheckBox = uicheckbox(app.ImportExperimentalDataPanel);
            app.InverseColorMapCheckBox.Text = 'Inverse ColorMap';
            app.SimpleFitToolPanel = uipanel(app.MuMag2022ToolboxNanoMagnetismGroupUniversityofLuxembourgPanel);
            app.SimpleFitToolPanel.Title = '---SimpleFit - Tool';
            app.SimpleFitButton = uibutton(app.SimpleFitToolPanel, 'push');
            app.SimpleFitButton.ButtonPushedFcn = createCallbackFcn(app, @SimpleFitButtonCallback, true);
            app.SimpleFitButton.Text = 'SimpleFit';
            app.CompareResultButton = uibutton(app.SimpleFitToolPanel, 'push');
            app.CompareResultButton.ButtonPushedFcn = createCallbackFcn(app, @CompareResultsButtonCallback, true);
            app.CompareResultButton.Text = 'Compare Result';
            app.SaveResultButton = uibutton(app.SimpleFitToolPanel, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SimpleFitSaveResultButtonCallback, true);
            app.SaveResultButton.Text = 'Save Result';
            app.q_mathrmmax1nmLabel = uilabel(app.SimpleFitToolPanel);
            app.q_mathrmmax1nmLabel.Interpreter = 'latex';
            app.q_mathrmmax1nmLabel.Text = '$q_{\mathrm{max}}$ [nm$^{-1}$]';
            app.q_mathrmmaxnm1EditField = uieditfield(app.SimpleFitToolPanel, 'numeric');
            app.q_mathrmmaxnm1EditField.Limits = [0 5];
            app.q_mathrmmaxnm1EditField.Value = 0.6;
            app.A_mathrmminpJmEditFieldLabel = uilabel(app.SimpleFitToolPanel);
            app.A_mathrmminpJmEditFieldLabel.Interpreter = 'latex';
            app.A_mathrmminpJmEditFieldLabel.Text = '$A_{\mathrm{min}}$ [pJ/m]';
            app.A_mathrmminpJmEditField = uieditfield(app.SimpleFitToolPanel, 'numeric');
            app.A_mathrmminpJmEditField.Limits = [0 Inf];
            app.A_mathrmminpJmEditField.ValueChangedFcn = createCallbackFcn(app, @SimpleFitAminEditCallback, true);
            app.A_mathrmminpJmEditField.Value = 1;
            app.A_mathrmmaxpJmEditFieldLabel = uilabel(app.SimpleFitToolPanel);
            app.A_mathrmmaxpJmEditFieldLabel.Interpreter = 'latex';
            app.A_mathrmmaxpJmEditFieldLabel.Text = '$A_{\mathrm{max}}$ [pJ/m]';
            app.A_mathrmmaxpJmEditField = uieditfield(app.SimpleFitToolPanel, 'numeric');
            app.A_mathrmmaxpJmEditField.Limits = [0 Inf];
            app.A_mathrmmaxpJmEditField.ValueChangedFcn = createCallbackFcn(app, @SimpleFitAmaxEditCallback, true);
            app.A_mathrmmaxpJmEditField.Value = 20;
            app.mu_0H_minmTLabel = uilabel(app.SimpleFitToolPanel);
            app.mu_0H_minmTLabel.Interpreter = 'latex';
            app.mu_0H_minmTLabel.Text = '$\mu_0H_{0}^{\mathrm{min}}$ [mT]';
            app.mu_0H_0mathrmminmTEditField = uieditfield(app.SimpleFitToolPanel, 'numeric');
            app.mu_0H_0mathrmminmTEditField.Limits = [0 Inf];
            app.mu_0H_0mathrmminmTEditField.Value = 75;
            app.SANSGeometryDropDownLabel = uilabel(app.SimpleFitToolPanel);
            app.SANSGeometryDropDownLabel.Interpreter = 'latex';
            app.SANSGeometryDropDownLabel.Text = 'SANS Geometry';
            app.SANSGeometryDropDown = uidropdown(app.SimpleFitToolPanel);
            app.SANSGeometryDropDown.Items = {'perpendicular', 'parallel'};
            app.SANSGeometryDropDown.Value = 'perpendicular';
            app.SamplesSpinner_3Label = uilabel(app.SimpleFitToolPanel);
            app.SamplesSpinner_3Label.Interpreter = 'latex';
            app.SamplesSpinner_3Label.Text = 'Samples';
            app.SamplesSpinner_3 = uispinner(app.SimpleFitToolPanel);
            app.SamplesSpinner_3.Limits = [10 500];
            app.SamplesSpinner_3.Value = 200;
            app.b_H1AmLabel = uilabel(app.SimpleFitToolPanel);
            app.b_H1AmLabel.Interpreter = 'latex';
            app.b_H1AmLabel.Text = '$b_{\mathrm{H}}$ [A$^{-1}$m$^{-1}$]';
            app.b_mathrmHA1m1EditField = uieditfield(app.SimpleFitToolPanel, 'numeric');
            app.b_mathrmHA1m1EditField.Limits = [0 Inf];
            app.b_mathrmHA1m1EditField.Value = 291000000;
            app.SweepFitToolPanel = uipanel(app.MuMag2022ToolboxNanoMagnetismGroupUniversityofLuxembourgPanel);
            app.SweepFitToolPanel.Title = '---SweepFit - Tool';
            app.SweepFitButton = uibutton(app.SweepFitToolPanel, 'push');
            app.SweepFitButton.ButtonPushedFcn = createCallbackFcn(app, @SweepFitButtonCallback, true);
            app.SweepFitButton.Text = 'SweepFit';
            app.SaveResultsButton = uibutton(app.SweepFitToolPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SweepFitSaveResultsButtonCallback, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.mu_0H_0mathrmminmTEditField_3Label = uilabel(app.SweepFitToolPanel);
            app.mu_0H_0mathrmminmTEditField_3Label.Interpreter = 'latex';
            app.mu_0H_0mathrmminmTEditField_3Label.Text = '$\mu_0H_{0}^{\mathrm{min}}$ [mT]';
            app.mu_0H_0mathrmminmTEditField_3 = uieditfield(app.SweepFitToolPanel, 'numeric');
            app.mu_0H_0mathrmminmTEditField_3.Limits = [0 20000];
            app.mu_0H_0mathrmminmTEditField_3.Value = 75;
            app.SANSGeometryDropDown_3Label = uilabel(app.SweepFitToolPanel);
            app.SANSGeometryDropDown_3Label.Interpreter = 'latex';
            app.SANSGeometryDropDown_3Label.Text = 'SANS Geometry';
            app.SANSGeometryDropDown_3 = uidropdown(app.SweepFitToolPanel);
            app.SANSGeometryDropDown_3.Items = {'perpendicular', 'parallel'};
            app.SANSGeometryDropDown_3.Value = 'perpendicular';
            app.GenerateSyntheticMSANSDataPanel = uipanel(app.MuMag2022ToolboxNanoMagnetismGroupUniversityofLuxembourgPanel);
            app.GenerateSyntheticMSANSDataPanel.Title = '---Generate Synthetic MSANS Data';
            app.GenSynDataButton = uibutton(app.GenerateSyntheticMSANSDataPanel, 'push');
            app.GenSynDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenSynDataButtonCallback, true);
            app.GenSynDataButton.Text = 'GenSynData';
            app.ApJmLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.ApJmLabel.Interpreter = 'latex';
            app.ApJmLabel.Text = '$A$ [pJ/m]';
            app.ApJmEditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.ApJmEditField.Limits = [0 Inf];
            app.ApJmEditField.Value = 10;
            app.SANSGeometryDropDown_2Label = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.SANSGeometryDropDown_2Label.Interpreter = 'latex';
            app.SANSGeometryDropDown_2Label.Text = 'SANS Geometry';
            app.SANSGeometryDropDown_2 = uidropdown(app.GenerateSyntheticMSANSDataPanel);
            app.SANSGeometryDropDown_2.Items = {'perpendicular', 'parallel'};
            app.SANSGeometryDropDown_2.Value = 'perpendicular';
            app.mu_0H_mathrmdmTEditFieldLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.mu_0H_mathrmdmTEditFieldLabel.Interpreter = 'latex';
            app.mu_0H_mathrmdmTEditFieldLabel.Text = '$\mu_0 H_{\mathrm{d}}$ [mT]';
            app.mu_0H_mathrmdmTEditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.mu_0H_mathrmdmTEditField.Limits = [0 Inf];
            app.mu_0H_mathrmdmTEditField.Value = 10;
            app.mu_0M_0mTEditFieldLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.mu_0M_0mTEditFieldLabel.Interpreter = 'latex';
            app.mu_0M_0mTEditFieldLabel.Text = '$\mu_0 M_{0}$ [mT]';
            app.mu_0M_0mTEditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.mu_0M_0mTEditField.Limits = [0 Inf];
            app.mu_0M_0mTEditField.Value = 1350;
            app.sigmastdLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.sigmastdLabel.Interpreter = 'latex';
            app.sigmastdLabel.Text = '$\sigma$ [cm$^{-1}$]';
            app.sigmacm1EditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.sigmacm1EditField.Limits = [0 Inf];
            app.sigmacm1EditField.Value = 1e-10;
            app.a_mathrmHLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.a_mathrmHLabel.Interpreter = 'latex';
            app.a_mathrmHLabel.Text = '$\mu_0\sqrt{\langle|H_{\mathrm{p}}|^2\rangle}$ [mT]';
            app.mu_0sqrtlangleH_mathrmp2ranglemTEditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.mu_0sqrtlangleH_mathrmp2ranglemTEditField.Limits = [0 Inf];
            app.mu_0sqrtlangleH_mathrmp2ranglemTEditField.Value = 5;
            app.a_mathrmMLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.a_mathrmMLabel.Interpreter = 'latex';
            app.a_mathrmMLabel.Text = '$\mu_0 \sqrt{\langle|M_{\mathrm{z}}|^2\rangle}$ [mT]';
            app.mu_0sqrtlangleM_mathrmz2ranglemTEditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.mu_0sqrtlangleM_mathrmz2ranglemTEditField.Limits = [0 Inf];
            app.mu_0sqrtlangleM_mathrmz2ranglemTEditField.Value = 10;
            app.xi_mathrmHnmEditFieldLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.xi_mathrmHnmEditFieldLabel.Interpreter = 'latex';
            app.xi_mathrmHnmEditFieldLabel.Text = '$\xi_{\mathrm{H}}$ [nm]';
            app.xi_mathrmHnmEditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.xi_mathrmHnmEditField.Limits = [0 Inf];
            app.xi_mathrmHnmEditField.Value = 5;
            app.mu_0H_0mTminLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.mu_0H_0mTminLabel.Interpreter = 'latex';
            app.mu_0H_0mTminLabel.Text = '$\mu_0 H_0$ [mT] (min)';
            app.mu_0H_0mTminEditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.mu_0H_0mTminEditField.Limits = [0 Inf];
            app.mu_0H_0mTminEditField.ValueChangedFcn = createCallbackFcn(app, @mu0Hmin_minEditCallback, true);
            app.mu_0H_0mTminEditField.Value = 200;
            app.mu_0H_0mTmaxLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.mu_0H_0mTmaxLabel.Interpreter = 'latex';
            app.mu_0H_0mTmaxLabel.Text = '$\mu_0 H_0$ [mT] (max)';
            app.mu_0H_0mTmaxEditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.mu_0H_0mTmaxEditField.Limits = [0 Inf];
            app.mu_0H_0mTmaxEditField.ValueChangedFcn = createCallbackFcn(app, @mu0Hmin_maxEditCallback, true);
            app.mu_0H_0mTmaxEditField.Value = 1600;
            app.SamplesSpinnerLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.SamplesSpinnerLabel.Interpreter = 'latex';
            app.SamplesSpinnerLabel.Text = 'Samples';
            app.SamplesSpinner = uispinner(app.GenerateSyntheticMSANSDataPanel);
            app.SamplesSpinner.Limits = [3 50];
            app.SamplesSpinner.Value = 8;
            app.q_mathrmminnm1EditFieldLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.q_mathrmminnm1EditFieldLabel.Interpreter = 'latex';
            app.q_mathrmminnm1EditFieldLabel.Text = '$q_{\mathrm{min}}$ [nm$^{-1}$]';
            app.q_mathrmminnm1EditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.q_mathrmminnm1EditField.Limits = [0 Inf];
            app.q_mathrmminnm1EditField.ValueChangedFcn = createCallbackFcn(app, @qmin_EditCallback, true);
            app.q_mathrmminnm1EditField.Value = 0.01;
            app.q_mathrmmaxnm1EditField_2Label = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.q_mathrmmaxnm1EditField_2Label.Interpreter = 'latex';
            app.q_mathrmmaxnm1EditField_2Label.Text = '$q_{\mathrm{max}}$ [nm$^{-1}$]';
            app.q_mathrmmaxnm1EditField_2 = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.q_mathrmmaxnm1EditField_2.Limits = [0 Inf];
            app.q_mathrmmaxnm1EditField_2.ValueChangedFcn = createCallbackFcn(app, @qmax_EditCallback, true);
            app.q_mathrmmaxnm1EditField_2.Value = 0.8;
            app.SamplesSpinner_2Label = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.SamplesSpinner_2Label.Interpreter = 'latex';
            app.SamplesSpinner_2Label.Text = 'Samples';
            app.SamplesSpinner_2 = uispinner(app.GenerateSyntheticMSANSDataPanel);
            app.SamplesSpinner_2.Limits = [20 1000];
            app.SamplesSpinner_2.Value = 200;
            app.xi_mathrmMnmEditFieldLabel = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.xi_mathrmMnmEditFieldLabel.Interpreter = 'latex';
            app.xi_mathrmMnmEditFieldLabel.Text = '$\xi_{\mathrm{M}}$ [nm]';
            app.xi_mathrmMnmEditField = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.xi_mathrmMnmEditField.Limits = [0 Inf];
            app.xi_mathrmMnmEditField.Value = 5;
            app.b_mathrmHA1m1EditField_2Label = uilabel(app.GenerateSyntheticMSANSDataPanel);
            app.b_mathrmHA1m1EditField_2Label.Interpreter = 'latex';
            app.b_mathrmHA1m1EditField_2Label.Text = '$b_{\mathrm{H}}$ [A$^{-1}$m$^{-1}$]';
            app.b_mathrmHA1m1EditField_2 = uieditfield(app.GenerateSyntheticMSANSDataPanel, 'numeric');
            app.b_mathrmHA1m1EditField_2.Limits = [0 Inf];
            app.b_mathrmHA1m1EditField_2.Value = 291000000;
            app.DemagFitToolPanel = uipanel(app.MuMag2022ToolboxNanoMagnetismGroupUniversityofLuxembourgPanel);
            app.DemagFitToolPanel.Title = '---DemagFit - Tool';
            app.A_mathrmminpJmEditField_2Label = uilabel(app.DemagFitToolPanel);
            app.A_mathrmminpJmEditField_2Label.Interpreter = 'latex';
            app.A_mathrmminpJmEditField_2Label.Text = '$A_{\mathrm{min}}$ [pJ/m]';
            app.A_mathrmminpJmEditField_2 = uieditfield(app.DemagFitToolPanel, 'numeric');
            app.A_mathrmminpJmEditField_2.Limits = [0 Inf];
            app.A_mathrmminpJmEditField_2.ValueChangedFcn = createCallbackFcn(app, @DemagFitAminEditCallback, true);
            app.A_mathrmminpJmEditField_2.Value = 1;
            app.A_mathrmmaxpJmEditField_2Label = uilabel(app.DemagFitToolPanel);
            app.A_mathrmmaxpJmEditField_2Label.Interpreter = 'latex';
            app.A_mathrmmaxpJmEditField_2Label.Text = '$A_{\mathrm{max}}$ [pJ/m]';
            app.A_mathrmmaxpJmEditField_2 = uieditfield(app.DemagFitToolPanel, 'numeric');
            app.A_mathrmmaxpJmEditField_2.Limits = [0 Inf];
            app.A_mathrmmaxpJmEditField_2.ValueChangedFcn = createCallbackFcn(app, @DemagFitAmaxEditCallback, true);
            app.A_mathrmmaxpJmEditField_2.Value = 20;
            app.SANSGeometryDropDown_4Label = uilabel(app.DemagFitToolPanel);
            app.SANSGeometryDropDown_4Label.Interpreter = 'latex';
            app.SANSGeometryDropDown_4Label.Text = 'SANS Geometry';
            app.SANSGeometryDropDown_4 = uidropdown(app.DemagFitToolPanel);
            app.SANSGeometryDropDown_4.Items = {'perpendicular', 'parallel'};
            app.SANSGeometryDropDown_4.Value = 'perpendicular';
            app.q_mathrmmaxnm1EditField_3Label = uilabel(app.DemagFitToolPanel);
            app.q_mathrmmaxnm1EditField_3Label.Interpreter = 'latex';
            app.q_mathrmmaxnm1EditField_3Label.Text = '$q_{\mathrm{max}}$ [nm$^{-1}$]';
            app.q_mathrmmaxnm1EditField_3 = uieditfield(app.DemagFitToolPanel, 'numeric');
            app.q_mathrmmaxnm1EditField_3.Limits = [0 5];
            app.q_mathrmmaxnm1EditField_3.Value = 0.6;
            app.mu_0H_0mathrmminmTLabel = uilabel(app.DemagFitToolPanel);
            app.mu_0H_0mathrmminmTLabel.Interpreter = 'latex';
            app.mu_0H_0mathrmminmTLabel.Text = '$\mu_0H_{0}^{\mathrm{min}}$ [mT]';
            app.mu_0H_0mathrmminmTEditField_2 = uieditfield(app.DemagFitToolPanel, 'numeric');
            app.mu_0H_0mathrmminmTEditField_2.Limits = [0 Inf];
            app.mu_0H_0mathrmminmTEditField_2.Value = 75;
            app.SamplesSpinner_4Label = uilabel(app.DemagFitToolPanel);
            app.SamplesSpinner_4Label.Interpreter = 'latex';
            app.SamplesSpinner_4Label.Text = 'Samples';
            app.SamplesSpinner_4 = uispinner(app.DemagFitToolPanel);
            app.SamplesSpinner_4.Limits = [10 500];
            app.SamplesSpinner_4.Value = 50;
            app.mu_0H_mathrmdmTminEditFieldLabel = uilabel(app.DemagFitToolPanel);
            app.mu_0H_mathrmdmTminEditFieldLabel.Interpreter = 'latex';
            app.mu_0H_mathrmdmTminEditFieldLabel.Text = '$\mu_0H_{\mathrm{d}}$ [mT] (min)';
            app.mu_0H_mathrmdmTminEditField = uieditfield(app.DemagFitToolPanel, 'numeric');
            app.mu_0H_mathrmdmTminEditField.Limits = [0 Inf];
            app.mu_0H_mathrmdmTminEditField.ValueChangedFcn = createCallbackFcn(app, @DemagFitHdemminEditCallback, true);
            app.mu_0H_mathrmdmTmaxEditFieldLabel = uilabel(app.DemagFitToolPanel);
            app.mu_0H_mathrmdmTmaxEditFieldLabel.Interpreter = 'latex';
            app.mu_0H_mathrmdmTmaxEditFieldLabel.Text = '$\mu_0H_{\mathrm{d}}$ [mT] (max)';
            app.mu_0H_mathrmdmTmaxEditField = uieditfield(app.DemagFitToolPanel, 'numeric');
            app.mu_0H_mathrmdmTmaxEditField.Limits = [0 Inf];
            app.mu_0H_mathrmdmTmaxEditField.ValueChangedFcn = createCallbackFcn(app, @DemagFitHdemmaxEditCallback, true);
            app.mu_0H_mathrmdmTmaxEditField.Value = 20;
            app.SamplesSpinner_5Label = uilabel(app.DemagFitToolPanel);
            app.SamplesSpinner_5Label.Interpreter = 'latex';
            app.SamplesSpinner_5Label.Text = 'Samples';
            app.SamplesSpinner_5 = uispinner(app.DemagFitToolPanel);
            app.SamplesSpinner_5.Limits = [10 500];
            app.SamplesSpinner_5.Value = 50;
            app.DemagFitButton = uibutton(app.DemagFitToolPanel, 'push');
            app.DemagFitButton.ButtonPushedFcn = createCallbackFcn(app, @DemagFitButtonCallback, true);
            app.DemagFitButton.Text = 'DemagFit';
            app.SaveResultsButton_2 = uibutton(app.DemagFitToolPanel, 'push');
            app.SaveResultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @DemagFitSaveButtonCallback, true);
            app.SaveResultsButton_2.Text = 'Save Results';
            % Show the figure after all components are created
            app.MuMagToolboxNanoMagnetismGroupUniversityofLuxembourgUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MuMag2022_Toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MuMagToolboxNanoMagnetismGroupUniversityofLuxembourgUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MuMagToolboxNanoMagnetismGroupUniversityofLuxembourgUIFigure)
        end
    end
end